classdef Oscilloscope < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    axes1                matlab.ui.control.UIAxes
    axes2                matlab.ui.control.UIAxes
    startButton          matlab.ui.control.Button
    clearButton          matlab.ui.control.Button
    savePlotsButton      matlab.ui.control.Button
    titleText            matlab.ui.control.Label
    warnText             matlab.ui.control.Label
    contMSPerDivButton   matlab.ui.control.DropDown
    text9                matlab.ui.control.Label
    thresholdSlider      matlab.ui.control.Slider
    vPerDivButton        matlab.ui.control.DropDown
    text10               matlab.ui.control.Label
    singleSpikeCheckbox  matlab.ui.control.CheckBox
    retriggerButton      matlab.ui.control.Button
    filterMenu           matlab.ui.control.DropDown
    text12               matlab.ui.control.Label
  end

  
  properties (Access = public)
    contMSPerDiv    % Description
    contPlotRescale % Description
    contSamples     % Description
    contTimeDivs    % Description
    filter          % Description
    filteredTrace   % Description
    filters         % Description
    inTrigger       % Description
    lastSpikeIndex  % Description
    maxContSamples  % Description
    rawData         % Description
    sampleRateHz    % Description
    samplesRead     % number of samples that have been read
    spikeIndices    % Description
    stopAtTraceEnd  % Description
    testMode        % Description
    thresholdV      % Description
    vDivs           % number of voltage divisions on the pots
    vPerDiv         % Description
  end
    
  methods (Access = private)
    
    function closeO(app, ~, ~, handles)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % we won't exit if the task is running
      if strcmp(app.startButton.Text, 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try delete(handles.ampDur); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('handles.lbj'); catch, end
          delete(app.figure1);
        case 'Cancel'
          return
      end
    end
      
    function collectData(app, obj, ~)
    % reads and processes stream data from the LabJack
      handles = obj.UserData;                               % obj.UserData is pointer to handles
      % If the continuous display scaling has just changed, refresh that plot
      if app.contPlotRescale
          setPlotLimits(app);
          clearContPlot(handles.plots, app, handles)
          clearTriggerPlot(handles.plots, app, handles)
          app.contPlotRescale = false;
      end
      % We need a new trace if we hit or exceeded the sample buffer limit on the previous call
      if app.samplesRead >= app.contSamples
          app.samplesRead = 0;
          app.lastSpikeIndex = app.lastSpikeIndex - app.contSamples;
          if app.stopAtTraceEnd                           % if single trace, stop data collection
              handles.plots.samplesPlotted = 0;
              doStartButton(app, handles);
  %                     startButton_Callback(app.startButton, event, handles);
          end
      end
      if (handles.plots.samplesPlotted > app.samplesRead)
          if handles.plots.singleSpike
              clearContPlot(handles.plots, app, handles);
          else
              clearAll(handles.plots, app, handles);
          end
      end
  
      % read and process any new data
  
      [dRaw, ~] = getStreamData(handles.lbj);                         % get stream data
  
      % the LabJack marks lost data as -9999.  We clip it out and don't do anything more. Typically lost data appear
      % at the end of the trace (after the buffer has filled).  Oddly, there is generally one spurious large value
      % (+10.115) that appears exactly 8 samples before the first error values.  We clip that out as well.
  
      dRaw(dRaw > 10.0) = [];                                         % and clip out all error values
      dRaw(dRaw == -9999) = [];                                         % and clip out all error values
  %     firstErrorIndex = find(dRaw == -9999, 1, 'first');              % get the first error index
  %     if ~isempty(firstErrorIndex)                                    % for one or more errors,
  %         fprintf('found an error\n');
  %         for i = firstErrorIndex - 10:firstErrorIndex + 10
  %             fprintf('%.3f ', dRaw(i));
  %         end
  %         fprintf('\n');
  %         if (firstErrorIndex > 8)                                    % clip out the spurious erroneous value
  %             dRaw(firstErrorIndex - 8) = [];
  %         end
  %         dRaw(dRaw == -9999) = [];                                   % and clip out all error values
  %     end
      % now we can process any valid data
      numNew = min(length(dRaw), app.contSamples - app.samplesRead);
      if numNew > 0
        app.rawData(app.samplesRead + 1:app.samplesRead + numNew) = dRaw(1:numNew);
        processSignals(handles.signals, app, app.samplesRead, numNew);
        app.samplesRead = app.samplesRead + numNew;
        doPlots(handles.plots, app, handles);
        if numNew < length(dRaw)                                      % more data read from LabJack?
          if app.stopAtTraceEnd                                      % if single trace, stop data collection
            handles.plots.samplesPlotted = 0;
            app.samplesRead = 0;
            doStartButton(app, handles);
          else
            numNew = length(dRaw) - numNew;
            app.rawData(1:numNew) = dRaw(end - numNew + 1:end);
            processSignals(handles.signals, app, 0, numNew);
            app.samplesRead = numNew;
          end
        end
      end
      guidata(app.startButton, handles);                          % save variables (guidata requires gui decendent
    end
      
    function collectError(~, ~, ~, ~)
            fprintf('data error');
            dbstack
        %     callStackString = GetCallStack(ME);
        % 	errorMessage = sprintf('Error in program %s.\nTraceback (most recent at top):\n%s\nError Message:\n%s',...
        % 		mfilename, callStackString, ME.message);
        % 	uiwait(errordlg(errorMessage));
    end
      
    function doStartButton(app, handles)
      % Start the task running
      if strcmp(app.startButton.Text, 'Start')                % if start mode, do the following
        fprintf(1,'\nOscilloscope v2.0\n %s\n', datestr(clock));
        app.stopAtTraceEnd = false;                  % clear any request to stop
        dataCollectRateHz = 25;                               % prevent overflow w/o blocking other activity
        handles.dataTimer = timer('Name', 'CollectData', 'ExecutionMode', 'fixedRate',...
            'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.collectError, handles},...
            'TimerFcn', {@app.collectData}, 'StartDelay', 0.050);   % startDelay allows rest of the gui to execute
        % clear the plots
        if handles.plots.singleSpike
            clearContPlot(handles.plots, app, handles);
        else
            clearAll(handles.plots, app, handles);
            app.lastSpikeIndex = 2 * app.maxContSamples;        % flag start of ISI sequence
        end
        app.startButton.Text = 'Stop';
        app.startButton.BackgroundColor = 'red';                % update button appearance
        OControlState(app, 'off', {app.startButton})
        startStream(handles.lbj);                               % start the LabJack
        start(handles.dataTimer);                               % start plots, data pickup, and data acquisition
      % Stop at trace end -- we're already running, so it's a the stop button
      elseif strcmp(app.startButton.Text, 'Stop')               % flag a stop at end of trace
        app.stopAtTraceEnd = true;
        app.startButton.Text = 'Stopping';
        app.startButton.BackgroundColor = 'yellow';
        OControlState(app, 'on', {app.startButton, app.clearButton, app.savePlotsButton})
        drawnow;
      % Stop immediately -- we're already stopping, so we've been asked to stop immediately
      else 
        stop(timerfind);                                      % stop/delete timers; pause data stream
        delete(timerfind);
        handles.dataTimer = 0;
        stopStream(handles.lbj);
%         clearAll(handles.signals, app);                            % stop audio processing
        app.inTrigger = false;
        app.startButton.Text = 'Start';
        app.startButton.BackgroundColor = 'green';
        OControlState(app, 'on', {app.startButton})
        drawnow;
      end
      guidata(app.startButton, handles);                  % save variables  
    end
      
    function fakeSpikeError(~, ~, ~, ~)
            fprintf('fake spike error\n');
    end
      
    function lbj = openLabJack(~)
        %  get hardware info and do not continue if daq device/drivers unavailable
  
      lbj = labJackU6;                        % create the daq object
      open(lbj);                              % open connection to the daq
      if isempty(lbj.handle)
        originalSize = get(0, 'DefaultUIControlFontSize');
        set(0, 'DefaultUIControlFontSize', 14);
        questdlg('Exit and check USB connections.', 'No LabJack Device Found', 'OK', 'OK');
        set(0, 'DefaultUIControlFontSize', originalSize);
      else
        fprintf(1,'Oscilloscope: LabJack Ready\n\n');
      end
      % create input channel list
      removeChannel(lbj, -1);                     % remove all input channels
      addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
      lbj.SampleRateHz = 5000;                    % sample rate (Hz)
      lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
  
      % configure LabJack for analog input streaming
  
      errorCode = streamConfigure(lbj);
      if errorCode > 0
        fprintf(1,'Oscilloscope: Unable to configure LabJack. Error %d.\n',errorCode);
        return
      end
    end
    
    function selectFilter(app)
      app.filter = app.filters{contains(app.filterMenu.Items, app.filterMenu.Value)};
    end
    
    %% setPlotLimits -- used when plot scaling changes
    function setPlotLimits(app)
      app.samplesRead = 0;
      app.spikeIndices = [];
      app.contSamples = app.contMSPerDiv / 1000.0 * app.sampleRateHz * app.contTimeDivs;
      vLimit = app.vPerDiv * app.vDivs / 2.0;
      app.thresholdV = max(-vLimit * 0.9, min(vLimit * 0.9, app.thresholdSlider.Value));
      app.thresholdSlider.Value = app.thresholdV;
      app.thresholdSlider.Limits = [-vLimit, vLimit];
    end
    
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openO(app, varargin)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
      
      % This function has no output args, see OutputFcn.
      % hObject    handle to figure
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      % varargin   command line arguments to O (see VARARGIN)
      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        app.testMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
      else
        app.testMode = true;
      end
      if app.testMode
        set(handles.warnText, 'string', 'Test Mode');
      end
      %       app.contMSPerDiv = str2double(contents{get(handles.contMSPerDivButton, 'Value')});
      app.contMSPerDiv = str2double(app.contMSPerDivButton.Value);
%       app.contSamples = 0;
      app.contTimeDivs = 20;
      app.inTrigger = false;
      app.samplesRead = 0;
      app.stopAtTraceEnd = false;
      app.thresholdV = 1.0;
      app.vDivs = 6;
      app.vPerDiv = 1.0;
    
      handles.output = hObject;                                      	% select default command line output
      set(hObject, 'CloseRequestFcn', {@app.closeO, handles});        % close function will close LabJack
      handles.lbj = openLabJack(app);                                 % LJ first, it has no contigencies
      app.sampleRateHz = handles.lbj.SampleRateHz;         
      maxMSPerDiv = str2double(app.contMSPerDivButton.Items{end});
      app.maxContSamples = app.contTimeDivs * maxMSPerDiv / 1000.0 * app.sampleRateHz;
      app.rawData = zeros(app.maxContSamples, 1);                    % raw data
      app.filteredTrace = zeros(app.maxContSamples, 1);              % filtered voltage trace
      setPlotLimits(app);                                            % set parameters for plots (after sampleRateHz)
      % make filters, usings the values in the filter menu
      filterStrings = app.filterMenu.Items;
      app.filters = cell(length(filterStrings), 1);
      for f = 1:length(filterStrings)
        cutOffHz = str2double(filterStrings{f});
        theFilter = design(fdesign.highpass('Fst,Fp,Ast,Ap', 0.5 * cutOffHz / app.sampleRateHz, ...
          2 * cutOffHz / app.sampleRateHz, 60, 1), 'butter');
        theFilter.persistentmemory = true;         % piecemeal filtering of trace
        theFilter.states = 1;                      % uses scalar expansion.
        app.filters{f} = theFilter;
      end
      selectFilter(app);

%       handles.data = OData(handles, app);                             % data next
      
      handles.signals = OSignalProcess(handles, app);                 % signal processing after data
      handles.plots = OPlots(handles, app);                              	% plots after handles.data
      handles.signals.fH = handles;
      
      % set up test mode
      movegui(hObject, 'northeast');
      guidata(hObject, handles);                                      % save the selection
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      %                 clearAll(handles.data, app);
      app.spikeIndices = [];
      app.inTrigger = false;
      app.samplesRead = 0;
      app.lastSpikeIndex = 2 * app.maxContSamples;                    % flag start with invalid index
      clearAll(handles.plots, app, handles);
      guidata(hObject, handles);
    end

    % Value changed function: contMSPerDivButton
    function contMSPerDivButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      newValue = str2double(app.contMSPerDivButton.Value);
%       contents = cellstr(get(hObject,'String'));
%       newValue = str2double(contents{get(hObject, 'Value')});
      if newValue ~= app.contMSPerDiv
        app.contMSPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')          % if we're not running
          setPlotLimits(app);
          clearContPlot(handles.plots, app, handles);
        else
          app.contPlotRescale = true;          % else let dataCollect do the update
        end
      end
    end

    % Value changed function: filterMenu
    function filterMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectFilter(app);
%                 selectFilter(handles.data, app);

    end

    % Button pushed function: retriggerButton
    function retriggerButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      clearTriggerPlot(handles.plots, app, handles);
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      OControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/Oscilloscope', ...
        'indeterminate','on');
      timeString = savePlot(app.axes1, '~/Desktop/Oscilloscope/FullTraces/', 'OSC', 'png');
      savePlot(app.axes2, '~/Desktop/Oscilloscope/FastTraces/', 'OSC', 'png', timeString);
      close(progress);
      OControlState(app, 'on', {});
    end

    % Value changed function: singleSpikeCheckbox
    function singleSpikeCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
                handles.plots.singleSpike = get(hObject, 'value');
                if handles.plots.singleSpike
                    set(handles.retriggerButton, 'Enable', 'on');
                    clearTriggerPlot(handles.plots, app, handles);
                else
                    set(handles.retriggerButton, 'Enable', 'off');
                end
            	guidata(hObject, handles);                                  % save change
    end

    % Callback function
    function singleTraceCheckbox_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      app.singleTrace = get(hObject, 'value');
      guidata(hObject, handles);                                  % save change
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % hObject    handle to startButton (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      doStartButton(app, handles);
    end

    % Value changed function: thresholdSlider
    function thresholdSlider_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      app.thresholdV = get(hObject, 'value');
      clearTriggerPlot(handles.plots, app, handles);
      guidata(hObject, handles);                                  % save change
    end

    % Value changed function: vPerDivButton
    function vPerDivButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      contents = cellstr(get(hObject,'String'));
      newValue = str2double(contents{get(hObject, 'Value')});
      if newValue ~= app.vPerDiv
        app.vPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')  % if we're not running
          setPlotLimits(app);
          clearAll(handles.plots, app, handles);
        else
          app.contPlotRescale = true;                % else let dataCollect do the update
        end
      end
      guidata(hObject, handles);                                  % save change
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.Tag = 'axes1';
      app.axes2 = uiaxes(app.figure1);
      app.axes2.Tag = 'axes2';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Oscilloscope';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.contMSPerDivButton = uidropdown(app.figure1);
      app.contMSPerDivButton.Items = {'10', '25', '50', '100', '250', '500', '1000'};
      app.contMSPerDivButton.ValueChangedFcn = createCallbackFcn(app, @contMSPerDivButton_Callback, true);
      app.contMSPerDivButton.Tag = 'contMSPerDivButton';
      app.contMSPerDivButton.Value = '100';
      app.text9 = uilabel(app.figure1);
      app.text9.Tag = 'text9';
      app.text9.Text = ' Time per Division (ms):';
      app.thresholdSlider = uislider(app.figure1);
      app.thresholdSlider.Limits = [-1 1];
      app.thresholdSlider.MajorTicks = [];
      app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
      app.thresholdSlider.MinorTicks = [];
      app.thresholdSlider.Tag = 'thresholdSlider';
      app.thresholdSlider.Value = 1;
      app.vPerDivButton = uidropdown(app.figure1);
      app.vPerDivButton.Items = {'0.1', '0.2', '0.5', '1.0', '2.0', '5.0'};
      app.vPerDivButton.ValueChangedFcn = createCallbackFcn(app, @vPerDivButton_Callback, true);
      app.vPerDivButton.Tag = 'vPerDivButton';
      app.vPerDivButton.Value = '1.0';
      app.text10 = uilabel(app.figure1);
      app.text10.Tag = 'text10';
      app.text10.Text = ' Volts per Division:';
      app.singleSpikeCheckbox = uicheckbox(app.figure1);
      app.singleSpikeCheckbox.ValueChangedFcn = createCallbackFcn(app, @singleSpikeCheckbox_Callback, true);
      app.singleSpikeCheckbox.Tag = 'singleSpikeCheckbox';
      app.singleSpikeCheckbox.Text = 'Single Spike';
      app.retriggerButton = uibutton(app.figure1, 'push');
      app.retriggerButton.ButtonPushedFcn = createCallbackFcn(app, @retriggerButton_Callback, true);
      app.retriggerButton.Tag = 'retriggerButton';
      app.retriggerButton.Enable = 'off';
      app.retriggerButton.Text = 'Re-trigger';
      app.filterMenu = uidropdown(app.figure1);
      app.filterMenu.Items = {'1', '100', '250'};
      app.filterMenu.ValueChangedFcn = createCallbackFcn(app, @filterMenu_Callback, true);
      app.filterMenu.Tag = 'filterMenu';
      app.filterMenu.Value = '1';
      app.text12 = uilabel(app.figure1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'High-pass Filter (Hz):';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Oscilloscope(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openO(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end