classdef ContrastThresholds < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1            matlab.ui.Figure
    Toolbar            matlab.ui.container.Toolbar
    PushTool           matlab.ui.container.toolbar.PushTool
    fitDataButton      matlab.ui.control.Button
    stimWarning        matlab.ui.control.Label
    testWarning        matlab.ui.control.Label
    loadDataButton     matlab.ui.control.Button
    text23             matlab.ui.control.Label
    text22             matlab.ui.control.Label
    text21             matlab.ui.control.Label
    resultsTable       matlab.ui.control.Table
    statusText         matlab.ui.control.Label
    staticText3        matlab.ui.control.Label
    staticText2        matlab.ui.control.Label
    staticText1        matlab.ui.control.Label
    runButton          matlab.ui.control.Button
    saveDataButton     matlab.ui.control.Button
    clearDataButton    matlab.ui.control.Button
    baseContrastMenu   matlab.ui.control.DropDown
    stimDurText        matlab.ui.control.EditField
    prestimDurText     matlab.ui.control.EditField
    intertrialDurText  matlab.ui.control.EditField
    stimRepsText       matlab.ui.control.EditField
    axes1              matlab.ui.control.UIAxes
  end

  properties (Access = public)
    baseContrasts     % the values of the base contrasts -- tuned to insure real differences in pixel values
    baseIndex         % index of the current base contrast
    blocksFit         % flags for which base constrasts have had a function fit
    ci95HighPC        % current higher 95% CI on threshold in percent contrast
    ci95LowPC         % current lower 95% CI on threshold in percent contrast
    doStim            % flag to present or not present visual stimuli
    fitXValues        % x values for the fit psychometric functions
    fitYValues        % y values for the fit psychometric functions
    hits              % number of hits for different base contrasts
    numBases          % the number of base contrasts
    numIncrements     % number of contrast increments per base    
    sampFreqHz        % sound sampling frequency
    stimParams        % description of the current stimuli
    stimStartTime     % start time for the current trial stimuli
    stimuli           % stimulus control object
    taskState         % task running, idle, etc
    taskTimer         % a timer to control runs of task sequencer
    testContrasts     % specific contrasts tested -- tuned to insure real differences in pixel values
    testIndex         % index for the current stimulus increment
    testMode          % no user responses
    theKey            % last key pressed
    thresholdsPC      % current thresholds in percent contrast
    tones             % array of tones used for user feedback
    trialsDone        % count of the number of trials completed in the current block (numIncrements x numBases)
    trialStartTime    % time of day for start of current trial
  end
  
  properties (Access = private)
  end
  
methods (Access = public)
  
  function timerErrorFcn(~, ~, ~)
    disp('timer error');
  end

  function fileName = doSaveData(app, autoSave)
      folderPath = [dataRoot(), '/CTData/Data/'];
      if ~isfolder(folderPath)
        mkdir(folderPath);
      end
      timeString = string(datetime('now', 'Format', 'MMM-dd-HHmmss'));
      if nargin >= 2 && autoSave
        fileName = autoSaveName('CT', timeString);
      else
        fileName = sprintf('CT-%s', timeString);
      end
      bI = app.baseIndex;
      tD = app.trialsDone;
      h = app.hits;
      fX = app.fitXValues;
      fY = app.fitYValues;
      bF = app.blocksFit;
      t = app.thresholdsPC;
      ciLow = app.ci95LowPC;
      ciHigh = app.ci95HighPC;
      rD = app.resultsTable.Data;
      save([folderPath fileName], 'bI', 'tD', 'h', 'fX', 'fY', 'bF', 'rD', 't', 'ciLow', 'ciHigh');
      % if we've succeeded, remove any backup versions
      if isfile([folderPath, 'temp.mat'])
        delete([folderPath, 'temp.mat']);
      end
  end
  
end

methods (Access = private)
  
  function clearOneContrast(app)
    app.trialsDone(app.baseIndex, :) = 0;
    app.hits(app.baseIndex, :) = 0;
    app.blocksFit(app.baseIndex) = 0;
    app.fitYValues(app.baseIndex, 1:end) = 0.5;
    app.thresholdsPC(app.baseIndex) = 0;
    app.ci95HighPC(app.baseIndex) = 0;
    app.ci95LowPC(app.baseIndex) = 0;
    tableData = app.resultsTable.Data;
    tableData{1, app.baseIndex} = 0;
    tableData{2, app.baseIndex} = '';
    tableData{3, app.baseIndex} = '';
    set(app.resultsTable, 'Data', tableData);
  end

  function keyPressed(app, ~, keyData)
    if strcmp(keyData.Key, 'escape')
      if app.taskState ~= ctTaskState.taskStopped
        app.taskState = ctTaskState.taskStopRunning;
      end
    elseif app.taskState == ctTaskState.taskWaitGoKey
      if strcmp(keyData.Key, 'downarrow')
        app.taskState = ctTaskState.taskDoStim;
      end
    elseif app.taskState == ctTaskState.taskWaitResponse
      if strcmp(keyData.Key, 'leftarrow')
        app.theKey = 'left';
        app.taskState = ctTaskState.taskProcessResponse;
      elseif strcmp(keyData.Key, 'rightarrow')
        app.theKey = 'right';
        app.taskState = ctTaskState.taskProcessResponse;
      end
    end
  end
    
  % Update components that require runtime configuration
  function addRuntimeConfigurations(app)
    
    % Set component properties that require runtime configuration
    app.resultsTable.BackgroundColor = [1 1 1; 0.9608 0.9608 0.9608];
    app.resultsTable.ColumnFormat = {[] [] [] [] []};
  end
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openContrastThresholds(app, varargin)
      % Check for existing instance using tag
      if ~isFirstAppInstance(app.figure1)
        delete(app);
        return;
      end      
      % There are some undiagnosable errors and warnings that occur
      % intermittently on the lab machine.  The following represents
      % efforts to suppress and elimiate those errors.
      suppressKnownWarnings();                    % stop annoying errors      
      addRuntimeConfigurations(app);           % Add runtime required configuration - Added by Migration Tool
      fprintf(1,'Contrast Thresholds v2.6\n %s\n', string(datetime));
      cleanOldFiles('CTData');
      deleteOtherTaskFiles('CTData')
      if ~isempty(varargin) && (strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test'))
        app.testMode = confirmTestMode();
      else
        app.testMode = false;
      end
      set(groot, 'DefaultLineLineWidth', 1.0);
      app.doStim = true;
      app.testIndex = 0;
      app.taskState = ctTaskState.taskStopped;
      app.theKey = '';
      app.testContrasts = [ 0.032	0.034 0.045 0.052 0.060;...
                            0.062 0.071 0.084 0.096 0.121;...
                            0.126 0.133 0.140 0.180 0.238;...
                            0.249 0.264 0.288 0.350 0.480;...
                            0.495 0.514 0.550 0.718 0.961];
      app.baseContrasts = [0.03 0.058 0.12 0.24 0.48];
      app.numIncrements = size(app.testContrasts, 2);
      app.numBases = length(app.baseContrasts);
      app.blocksFit = zeros(1, app.numBases);
      app.thresholdsPC = zeros(1, app.numBases);
      app.ci95HighPC = zeros(1, app.numBases);
      app.ci95LowPC = zeros(1, app.numBases);
      curveSteps = 50;
      for base = 1:app.numIncrements
        stepSize = (app.testContrasts(base, app.numIncrements) - app.baseContrasts(base)) / (curveSteps - 1);
        app.fitXValues(base, :) = app.baseContrasts(base):stepSize:app.testContrasts(base, app.numIncrements);
      end
      app.fitYValues = zeros(app.numBases, curveSteps);
      app.hits = zeros(app.numBases, app.numIncrements);
      % app.hitRates = zeros(app.numBases, app.numIncrements + 1);
      app.stimParams = struct;
      app.trialsDone = zeros(app.numBases, app.numIncrements);    % counts of completed for each stimulus
      app.trialStartTime = [];

      contrastStrings = cell(1, app.numBases);
      for i = 1:app.numBases
        contrastStrings{i} = sprintf('%.0f%%', app.baseContrasts(i) * 100.0);
      end
      app.baseContrastMenu.Items = contrastStrings;
      app.baseIndex = find(contains(app.baseContrastMenu.Items, app.baseContrastMenu.Value));
      
      audioFiles = {'Tone0250.wav', 'Tone2000.wav', 'Tone4000.wav'};
      app.tones = [];
      for i = 1:length(audioFiles)
        [y, app.sampFreqHz] = audioread(char(audioFiles(i)));
        app.tones = [app.tones y(:)];
      end
      app.tones = app.tones';
      
      tableData = app.resultsTable.Data;
      for c = 1:app.numBases
        tableData{1, c} = 0;
        tableData{2, c} = '';
        tableData{3, c} = '';
      end
      set(app.resultsTable, 'Data', tableData);
     
      rng('shuffle');
      app.stimuli = ctStimuli(app);
      %     testStimuli(app.stimuli);                         % check the screen for contrasts available
      
      ctDrawStatusText(app, 'idle');
      if app.testMode
        set(app.testWarning, 'text', 'Test Mode');
      end
      if ~app.doStim
        set(app.stimWarning, 'text', 'No Stimuli');
      end
      buttonStruct.keyDown = '';                                            % UserDate needs to be a struct
      set(app.runButton, 'userData', buttonStruct);
      set(app.figure1, 'visible', 'on');
      app.taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
        'errorFcn', {@(~,~)timerErrorFcn(app)}, 'timerFcn', {@ctTaskController, app});
      start(app.taskTimer);
      set(app.figure1, 'WindowKeyPressFcn', @app.keyPressed)
    end

    % Value changed function: baseContrastMenu
    function baseContrastMenu_Callback(app, event)
      app.baseIndex = find(contains(app.baseContrastMenu.Items, app.baseContrastMenu.Value));
    end

    % Button pushed function: clearDataButton
    function clearDataCallback(app, event)
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, ...
            sprintf('Really clear data for %s base contrast? (This cannot be undone.)', ...
            get(app.baseContrastMenu, 'value')), 'Clear Data');
      switch selection
        case 'OK'
          clearOneContrast(app);
          ctDrawHitRates(app, true);
        case 'Cancel'
          return
      end
    end

    % Button pushed function: fitDataButton
    function fitDataButtonPushed(app, event)
      ctLinearFits(app);
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      ctControlState(app, 'off', {app.loadDataButton});
      app.figure1.Visible = 'off';     % make sure uiputfile window is visible
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', [dataRoot(), '/CTData/Data']);
      app.figure1.Visible = 'on';     % make sure uiputfile window is visible
      if fileName ~= 0
        stop(app.taskTimer);                                        % stop timer to pause data stream
        load([filePath fileName], 'bI', 'tD', 'h', 'fX', 'fY', 'bF', 'rD', 't', 'ciLow', 'ciHigh');
        app.baseIndex = bI;
        app.baseContrastMenu.Value = app.baseContrastMenu.Items{app.baseIndex};
        app.trialsDone = tD;
        app.hits = h;
        app.blocksFit = bF;
        app.fitXValues = fX;
        app.fitYValues = fY;
        app.resultsTable.Data = rD;
        app.thresholdsPC = t;
        app.ci95HighPC = ciHigh;
        app.ci95LowPC = ciLow;
        ctDrawHitRates(app, true);
        start(app.taskTimer);
      end
      ctControlState(app, 'on', {app.loadDataButton});
    end

    % Button pushed function: runButton
    function runButton_Callback(app, event)
      if strcmp(app.runButton.Text, 'Stop')           % if we are running, just set the abort flag
        app.taskState = ctTaskState.taskStopRunning;
      else
        stimReps = str2double(app.stimRepsText.Value);
        if sum(app.trialsDone(app.baseIndex, :)) < stimReps * app.numIncrements
          app.taskState = ctTaskState.taskStartRunning;
        else
          uialert(app.figure1, sprintf(['%d repeats of %s contrast have already been done.\n', ...
                'Increase the value for "Stop when n>="'], ...
                stimReps, app.baseContrastMenu.Value), 'At Repeat Limit');
        end
      end
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      ctControlState(app, 'off', {});
      progress = uiprogressdlg(app.figure1, 'title', 'Saving Data and Plots', 'message', ...
        ['Saving to ', dataRoot(), '/CTData'], 'indeterminate','on');
      fileName = doSaveData(app);
      timeString = savePlot(app.axes1, 'CTData/Plots/', 'CT', 'png');
      savePlot(ctLinearFits(app), 'CTData/Fits/', 'CT', 'png');
      saveTable(app.resultsTable, 'CTData/Tables/', 'CT', timeString);
      close(progress);
      ctControlState(app, 'on', {});
      uiconfirm(app.figure1, sprintf('Data saved successfully to %s', fileName), 'Save Data', 'Icon', 'success');
    end

    % Close request function: figure1
    function windowCloseRequest(app, event)
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try delete(app.stimuli); catch, end
          delete(app.figure1);                              % close the program window
          close all force
        case 'Cancel'
          return
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @windowCloseRequest, true);
      app.figure1.Tag = 'ContrastThresholds';
      app.Toolbar = uitoolbar(app.figure1);
      app.PushTool = uipushtool(app.Toolbar);
      app.axes1 = uiaxes(app.figure1);
      app.axes1.XLim = [2 100];
      app.axes1.YLim = [0 100];
      app.axes1.XTick = [5 6 7 8 9 10 20 30 40 50 60 80 100];
      app.axes1.XScale = 'log';
      app.axes1.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'; '20'; '30'; '40'; '50'; '60'; '80'; '100'};
      app.axes1.YTick = [0 10 20 30 40 50 60 70 80 90 100];
      app.axes1.YTickLabel = {'00'; ''; '20'; ''; '40'; ''; '60'; ''; '80'; ''; '100'};
      app.axes1.Tag = 'axes1';
      app.stimRepsText = uieditfield(app.figure1, 'text');
      app.stimRepsText.Tag = 'stimRepsText';
      app.stimRepsText.Value = '5';
      app.intertrialDurText = uieditfield(app.figure1, 'text');
      app.intertrialDurText.Tag = 'intertrialDurText';
      app.intertrialDurText.Value = '1.0';
      app.prestimDurText = uieditfield(app.figure1, 'text');
      app.prestimDurText.Tag = 'prestimDurText';
      app.prestimDurText.Value = '1.0';
      app.stimDurText = uieditfield(app.figure1, 'text');
      app.stimDurText.Tag = 'stimDurText';
      app.stimDurText.Value = '0.25';
      app.baseContrastMenu = uidropdown(app.figure1);
      app.baseContrastMenu.Items = {'3%', '6%', '12%', '24%', '48%'};
      app.baseContrastMenu.ValueChangedFcn = createCallbackFcn(app, @baseContrastMenu_Callback, true);
      app.baseContrastMenu.Tag = 'baseContrastMenu';
      app.baseContrastMenu.Value = '48%';
      app.clearDataButton = uibutton(app.figure1, 'push');
      app.clearDataButton.ButtonPushedFcn = createCallbackFcn(app, @clearDataCallback, true);
      app.clearDataButton.Tag = 'clearDataButton';
      app.clearDataButton.Text = 'Clear Data';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.runButton = uibutton(app.figure1, 'push');
      app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButton_Callback, true);
      app.runButton.Tag = 'runButton';
      app.runButton.Text = 'Start';
      app.staticText1 = uilabel(app.figure1);
      app.staticText1.Tag = 'staticText1';
      app.staticText1.Text = 'Stop When n >=';
      app.staticText2 = uilabel(app.figure1);
      app.staticText2.Tag = 'staticText2';
      app.staticText2.Text = 'Test Stim. Dur. (s):';
      app.staticText3 = uilabel(app.figure1);
      app.staticText3.Tag = 'staticText3';
      app.staticText3.Text = 'Base Stim. Dur. (s):';
      app.statusText = uilabel(app.figure1);
      app.statusText.Tag = 'statusText';
      app.statusText.Text = 'Status';
      app.resultsTable = uitable(app.figure1);
      app.resultsTable.ColumnName = {'3%'; '6%'; '12%'; '24%'; '48%'};
      app.resultsTable.RowName = {'Repeats Done'; 'Threshold (%)'; 'Thresh. 95% CI'};
      app.resultsTable.ColumnEditable = [false false false false false];
      app.resultsTable.Tag = 'resultsTable';
      app.text21 = uilabel(app.figure1);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Base Contrast:';
      app.text22 = uilabel(app.figure1);
      app.text22.Tag = 'text22';
      app.text22.Text = 'Inter-trial Dur. (s):';
      app.text23 = uilabel(app.figure1);
      app.text23.Tag = 'text23';
      app.text23.Text = {'NSCI 20100 Contrast Increment'; 'Thresholds Laboratory'};
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.testWarning = uilabel(app.figure1);
      app.testWarning.Tag = 'testWarning';
      app.testWarning.Text = '';
      app.stimWarning = uilabel(app.figure1);
      app.stimWarning.Tag = 'stimWarning';
      app.stimWarning.Text = '';
      app.fitDataButton = uibutton(app.figure1, 'push');
      app.fitDataButton.ButtonPushedFcn = createCallbackFcn(app, @fitDataButtonPushed, true);
      app.fitDataButton.Tag = 'saveDataButton';
      app.fitDataButton.Enable = 'off';
      app.fitDataButton.Text = 'Show Fits';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ContrastThresholds(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openContrastThresholds(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end