classdef contrastThresholds_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1            matlab.ui.Figure
    axes1              matlab.ui.control.UIAxes
    stimRepsText       matlab.ui.control.EditField
    intertrialDurText  matlab.ui.control.EditField
    prestimDurText     matlab.ui.control.EditField
    stimDurText        matlab.ui.control.EditField
    baseContrastMenu   matlab.ui.control.DropDown
    clearDataButton    matlab.ui.control.Button
    savePlotsButton    matlab.ui.control.Button
    saveDataButton     matlab.ui.control.Button
    runButton          matlab.ui.control.Button
    staticText1        matlab.ui.control.Label
    staticText2        matlab.ui.control.Label
    staticText3        matlab.ui.control.Label
    statusText         matlab.ui.control.Label
    resultsTable       matlab.ui.control.Table
    text21             matlab.ui.control.Label
    text22             matlab.ui.control.Label
    text23             matlab.ui.control.Label
    showHideButton     matlab.ui.control.Button
    loadDataButton     matlab.ui.control.Button
    testWarning        matlab.ui.control.Label
    stimWarning        matlab.ui.control.Label
  end

    
methods (Access = private)
  function doShowHide(app, handles)

    if strcmp(get(app.showHideButton, 'String'), 'Hide Display')
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      set(app.showHideButton, 'string', 'Show Display', 'backgroundColor', 'red');
      set(handles.runButton, 'string', 'Run', 'backgroundColor', [0.9412 0.9412 0.9412]);
      state = 'off';
    elseif strcmp(get(app.showHideButton, 'String'), 'Show Display')
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                  % restore stimulus display
      end
      set(app.showHideButton, 'string', 'Hide Display','backgroundColor', [0.9412 0.9412 0.9412]);
      set(handles.runButton, 'string', 'Run','backgroundColor', 'green');
      state = 'on';
    end
    ctControlState(handles, state, {app.showHideButton});
    guidata(app.showHideButton, handles);               % save the changes
  end
  
  function keyboardCheck(app, obj, ~)
    handles = get(obj, 'UserData');
    data = handles.data;
    [~, ~, keyCode] = KbCheck(-1);
    if keyCode(KbName('ESCAPE'))
      if data.taskState ~= ctTaskState.taskStopped
        data.taskState = ctTaskState.taskStopRunning;
      elseif keyCode(KbName('LeftShift'))
        doShowHide(app, handles);
      end
    elseif data.taskState == ctTaskState.taskWaitGoKey
      if keyCode(KbName('DownArrow'))
        data.taskState = ctTaskState.taskDoStim;
      end
    elseif data.taskState == ctTaskState.taskWaitResponse
      if keyCode(KbName('LeftArrow'))
        data.theKey = 'left';
        data.taskState = ctTaskState.taskProcessResponse;
      elseif keyCode(KbName('RightArrow'))
        data.theKey = 'right';
        data.taskState = ctTaskState.taskProcessResponse;
      end
    end
  end
  
  function timerErrorFcn(~, ~, ~, ~)
    disp('timer error');
  end
  
  % Update components that require runtime configuration
  function addRuntimeConfigurations(app)
    
    % Set component properties that require runtime configuration
    app.resultsTable.BackgroundColor = [1 1 1; 0.9608 0.9608 0.9608];
    app.resultsTable.ColumnFormat = {[] [] [] [] []};
  end
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openContrastThresholds(app, varargin)
      % Add runtime required configuration - Added by Migration Tool
      addRuntimeConfigurations(app);
      
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
      
      rng('shuffle');
      handles.data = ctTaskData(handles.baseContrastMenu);
      handles.output = hObject;                                     % select default command line output
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                              % restore stimulus display
      end
      
      %     testStimuli(handles.stimuli, handles);                  % check the screen for contrasts available
      
      ctDrawStatusText(handles, 'idle');
      movegui(handles.figure1, 'northwest');
      set(handles.figure1, 'visible', 'on');
      if handles.data.testMode
        set(handles.testWarning, 'string', 'Test Mode');
      end
      if ~handles.data.doStimDisplay
        set(handles.stimWarning, 'string', 'No Display');
      elseif ~handles.data.doStim
        set(handles.stimWarning, 'string', 'No Stimuli');
      end
%       get(handles.runButton, 'UserData');
      buttonStruct.keyDown = '';                                            % UserDate needs to be a struct
      set(app.runButton, 'userData', buttonStruct);

      guidata(hObject, handles);                                    % save the selection
      
      KbName('UnifyKeyNames');
      taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
        'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@ctTaskController});
      keyTimer = timer('name', 'KeyTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
        'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@app.keyboardCheck});
      start(taskTimer);
      start(keyTimer);
    end

    % Button pushed function: clearDataButton
    function clearDataCallback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
      
      baseIndex = get(handles.baseContrastMenu, 'value');
      strings = get(handles.baseContrastMenu, 'string');
      originalSize = get(0, 'DefaultUIControlFontSize');
      set(0, 'DefaultUIControlFontSize', 14);
      selection = questdlg(...
        sprintf('Really clear data for %s base contrast? (This cannot be undone).', strings{baseIndex}),...
        'Clear Data', 'Yes', 'No', 'Yes');
      set(0, 'DefaultUIControlFontSize', originalSize);
      if strcmp(selection, 'Yes')
        clearAll(handles.data, handles.baseContrastMenu, handles.resultsTable);
        ctDrawHitRates(handles, true);
        guidata(hObject, handles);
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
      
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      ctControlState(handles, 'off', {handles.loadDataButton});
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop/');
      if fileName ~= 0
        stop(timerfind);                                            % stop/delete timers; pause data stream
        delete(timerfind);
        load([filePath fileName], 'd');
        handles.data = d;
        handles = ctDrawHitRates(handles, true);
        taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
          'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@ctTaskController});
        keyTimer = timer('name', 'KeyTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
          'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@app.keyboardCheck});
        start(taskTimer);
        start(keyTimer);
      end
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                  % restore stimulus display
      end
      set(handles.runButton, 'backgroundColor', 'green');
      ctControlState(handles, 'on', {handles.loadDataButton});
      guidata(hObject, handles);                                      % save the selections
    end

    % Button pushed function: runButton
    function runButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
      
      if strcmp(get(hObject, 'String'), 'Stop')           % if we are running, just set the abort flag
        handles.data.taskState = ctTaskState.taskStopRunning;
      else
        baseIndex = get(handles.baseContrastMenu, 'value');
        stimReps = str2double(get(handles.stimRepsText, 'string'));
        data = handles.data;
        if sum(data.trialsDone(baseIndex, :)) < stimReps * data.numIncrements
          data.taskState = ctTaskState.taskStartRunning;
        else
          contrastStrings = get(handles.baseContrastMenu, 'string');
          originalSize = get(0, 'DefaultUIControlFontSize');
          set(0, 'DefaultUIControlFontSize', 14);
          helpdlg(sprintf('%d reps of %s contrast have already been done', stimReps, contrastStrings{baseIndex}));
          set(0, 'DefaultUIControlFontSize', originalSize);
        end
      end
      guidata(hObject, handles);                          % save the changes
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      ctControlState(handles, 'off', {handles.saveDataButton});
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/ContrastData.mat');
      if fileName ~= 0
        d = handles.data;
        save([filePath fileName], 'd');
      end
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();
      end
      set(handles.runButton, 'backgroundColor', 'green');
      ctControlState(handles, 'on', {handles.saveDataButton});
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      ctControlState(handles, 'off', {handles.savePlotsButton});
      [fileName, filePath] = uiputfile('*.pdf', 'Save Window Image as PDF', '~/Desktop/ContrastThresholds.pdf');
      if fileName ~= 0
        set(handles.figure1, 'PaperUnits', 'inches');
        figurePos = get(handles.figure1, 'position');
        widthInch = figurePos(3) / 72;
        heightInch = figurePos(4) / 72;
        set(handles.figure1, 'PaperSize', [widthInch + 1.0, heightInch + 1.0]);
        set(handles.figure1, 'PaperPosition', [0.5, 0.5, widthInch, heightInch]);
        set(handles.figure1, 'InvertHardcopy', 'off');
        print(handles.figure1, [filePath fileName],'-dpdf', '-r600');
      end
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();
      end
      set(handles.runButton, 'backgroundColor', 'green');
      ctControlState(handles, 'on', {handles.savePlotsButton});
    end

    % Button pushed function: showHideButton
    function showHideButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      doShowHide(app, handles);
    end

    % Close request function: figure1
    function windowCloseRequest(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          %           try delete(handles.visStim); catch, end
          try delete(timerfind); catch, end
          if handles.data.doStimDisplay
            try cleanup(handles.stimuli); catch, end
          end
          delete(app.figure1);                              % close the program window
        case 'Cancel'
          return
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'contrastThresholds';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @windowCloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.XLim = [2 100];
      app.axes1.YLim = [0 100];
      app.axes1.XTick = [5 6 7 8 9 10 20 30 40 50 60 80 100];
      app.axes1.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'; '20'; '30'; '40'; '50'; '60'; '80'; '100'};
      app.axes1.YTick = [0 20 40 60 80 100];
      app.axes1.YTickLabel = {'10'; '20'; '40'; '60'; '80'; '100'};
      app.axes1.XScale = 'log';
      app.axes1.Tag = 'axes1';
      app.stimRepsText = uieditfield(app.figure1, 'text');
      app.stimRepsText.Tag = 'stimRepsText';
      app.stimRepsText.Value = '25';
      app.intertrialDurText = uieditfield(app.figure1, 'text');
      app.intertrialDurText.Tag = 'intertrialDurText';
      app.intertrialDurText.Value = '1.0';
      app.prestimDurText = uieditfield(app.figure1, 'text');
      app.prestimDurText.Tag = 'prestimDurText';
      app.prestimDurText.Value = '1.0';
      app.stimDurText = uieditfield(app.figure1, 'text');
      app.stimDurText.Tag = 'stimDurText';
      app.stimDurText.Value = '0.25';
      app.baseContrastMenu = uidropdown(app.figure1);
      app.baseContrastMenu.Items = {'3%', '6%', '12%', '24%', '48%'};
      app.baseContrastMenu.Tag = 'baseContrastMenu';
      app.baseContrastMenu.Value = '48%';
      app.clearDataButton = uibutton(app.figure1, 'push');
      app.clearDataButton.ButtonPushedFcn = createCallbackFcn(app, @clearDataCallback, true);
      app.clearDataButton.Tag = 'clearDataButton';
      app.clearDataButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.runButton = uibutton(app.figure1, 'push');
      app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButton_Callback, true);
      app.runButton.Tag = 'runButton';
      app.runButton.Text = 'Start';
      app.staticText1 = uilabel(app.figure1);
      app.staticText1.Tag = 'staticText1';
      app.staticText1.Text = 'Stop After Block:';
      app.staticText2 = uilabel(app.figure1);
      app.staticText2.Tag = 'staticText2';
      app.staticText2.Text = 'Test Stim. Dur. (s):';
      app.staticText3 = uilabel(app.figure1);
      app.staticText3.Tag = 'staticText3';
      app.staticText3.Text = 'Base Stim. Dur. (s):';
      app.statusText = uilabel(app.figure1);
      app.statusText.Tag = 'statusText';
      app.statusText.Text = 'Status';
      app.resultsTable = uitable(app.figure1);
      app.resultsTable.ColumnName = {'3%'; '6%'; '12%'; '24%'; '48%'};
      app.resultsTable.RowName = {'Blocks Done'; 'Threshold Contrast'; ''; ''};
      app.resultsTable.ColumnEditable = [false false false false false];
      app.resultsTable.Tag = 'resultsTable';
      app.text21 = uilabel(app.figure1);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Base Contrast:';
      app.text22 = uilabel(app.figure1);
      app.text22.Tag = 'text22';
      app.text22.Text = 'Inter-trial Dur. (s):';
      app.text23 = uilabel(app.figure1);
      app.text23.Tag = 'text23';
      app.text23.Text = {'NSCI 20100 Neuroscience Laboratory'; 'Contrast Increment Thresholds'};
      app.showHideButton = uibutton(app.figure1, 'push');
      app.showHideButton.ButtonPushedFcn = createCallbackFcn(app, @showHideButton_Callback, true);
      app.showHideButton.Tag = 'showHideButton';
      app.showHideButton.Text = 'Hide Display';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.testWarning = uilabel(app.figure1);
      app.testWarning.Tag = 'testWarning';
      app.testWarning.Text = '';
      app.stimWarning = uilabel(app.figure1);
      app.stimWarning.Tag = 'stimWarning';
      app.stimWarning.Text = '';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = contrastThresholds_App(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openContrastThresholds(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end