classdef SaccadeRT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        playSounds        matlab.ui.control.CheckBox
        text3_2           matlab.ui.control.Label
        thresholdDegText  matlab.ui.control.EditField
        thresholdDPSText  matlab.ui.control.EditField
        ThresholdType     matlab.ui.container.ButtonGroup
        SpeedButton       matlab.ui.control.RadioButton
        PositionButton    matlab.ui.control.RadioButton
        limitText         matlab.ui.control.Label
        text2_2           matlab.ui.control.Label
        stopAfterText     matlab.ui.control.EditField
        Filter60Hz        matlab.ui.control.CheckBox
        calibrationText   matlab.ui.control.Label
        warnText          matlab.ui.control.Label
        loadDataButton    matlab.ui.control.Button
        saveDataButton    matlab.ui.control.Button
        titleText         matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        viewDistanceText  matlab.ui.control.EditField
        clearButton       matlab.ui.control.Button
        startButton       matlab.ui.control.Button
        stepAxes          matlab.ui.control.UIAxes
        gapAxes           matlab.ui.control.UIAxes
        velAxes           matlab.ui.control.UIAxes
        posAxes           matlab.ui.control.UIAxes
    end

  properties (Constant)
    
    degPerRadian = 57.2958;
    maxViewDistCM = 100;
    mmPerInch = 25.40;
    saccadeTraceS = 0.250;
    
    kNormal = 0;                    % task modes
    kDebug = 1;
    kTiming = 2;
    
    kCenteringTrial = 0;             % trial types
    kGapTrial = 1;
    kStepTrial = 2;
    numTrialTypes = 2;
    
    kBlankStim = 1;                  % stimulus images
    kLeftStim = 2;
    kRightStim = 3;
    kBothStim = 4;
    kTestStim = 5;
    
    kLeft = -1;
    kRight = 1;
  end
  
  properties (Access = public)
    blocksDone;           % number of blocks completed
    calTrialsDone         % number of calibration trials done
    dataState = RTDataState.dataIdle; % state for data collection system
    dataTimer;            % timer for collecting data periodically
    fixOffTimeS = 0;      % trial time when fixation point went off
    gapDurS = 0.200;      % duration of fixation gap
    inFirstHalf = true;   % Collecting first half of fake data
    lbj                   % handle for LabJack object
    numSummed = 0;        % number of summed traces
    posAvg;               % the average position trace
    posSummed;            % number of position traces summed
    posTrace;             % the position trace
    posVelPlots;          % handle to RTPosVelPlots object
    prestimDurS;          % duration of the prestim period
    prestimTimeS;         % time prestim period start on current trial
    rawData;              % trace of unfiltered data
    rtDists;              % handle to RTDist objects
    saccades;             % handle to RTSaccades object
    saccadeSamples;       % number of samples included in saccade
    samplesRead = 0;      % number of samples read in current sweep
    stepSign = 0;    % direction of the current step
    stepSizeDeg = 10.0;   % size of the stimulus steps
    taskMode;             % taskMode is set in OpenRT, not here
    taskState = RTTaskState.taskStarttrial; % state for task system
    taskTimer;            % timer for controling task system
    targetTimeS = 0;      % time target appeared in current trial
    trialDurS;            % trial duration in seconds
    trialSamples;         % number of samples in a trial
    trialStartTime = 0;   % trial start as a time vector
    trialType;            % type of current trial
    trialTypesDone;       % count of different trial types completed
    velAvg;               % average velocity trace
    velSummed;            % summed trace of velocities
    velTrace;             % most recent velocity trace
    visStim;              % handle to RTStimuli object
    voltage = 0;          % the output voltage used in debugging
  end
  
  properties (Access = private)
    saccStartIndex = 0;   % trace index for the start of current saccade
    saccEndIndex = 0;     % trace index for the end of current saccade
    tone = 0;             % tone to play when a saccade is detected
    audioFreqHz = 0;      % frequency of audio out samples
  end
  
  methods (Access = private)

    function atLimit = atTrialLimit(app)
      trialLimit = str2double(app.stopAfterText.Value);
      atLimit = app.rtDists{app.kStepTrial}.n >= trialLimit && app.rtDists{app.kGapTrial}.n >= trialLimit;
      if atLimit
        app.limitText.Text = 'At requested n';
      else
        app.limitText.Text = '';
      end
    end

    function clearTraces(app)
      app.blocksDone = 0;
      app.calTrialsDone = 0;
      app.numSummed = 0;
      app.posTrace = zeros(app.trialSamples, 1);                      % trial RT position trace
      app.posSummed = zeros(app.saccadeSamples, 1);                   % summed position traces
      app.posAvg = zeros(app.saccadeSamples, 1);                      % averaged position traces
      app.rawData = zeros(app.trialSamples, app.lbj.numChannels);   	% raw data
      app.trialTypesDone = zeros(1, app.numTrialTypes);               % table of completed trials in block
      app.velTrace = zeros(app.trialSamples, 1);                      % trial RT velocity trace
      app.velSummed = zeros(app.saccadeSamples, 1);                   % summed position traces
      app.velAvg = zeros(app.saccadeSamples, 1);                      % averaged position traces
   end
    
    function closeRT(app, ~, ~)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % fist check whether the task is running
      if strcmp(app.startButton.Text, 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try delete(app.visStim); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                                            % close the program window
        case 'Cancel'
          return
      end
    end
            
    function collectData(~, ~, ~, app)
      % reads stream data from the LabJack
      switch app.dataState
        case RTDataState.dataIdle
          return;
        case RTDataState.dataCollect
          [dRaw, ~] = getStreamData(app.lbj);                             %#ok<*NASGU> % get stream data
          numNew = min(length(dRaw), app.trialSamples - app.samplesRead);
          app.rawData(app.samplesRead + 1:app.samplesRead + numNew, :) = dRaw(1:numNew, :);
          app.samplesRead = app.samplesRead + numNew;
          if (app.samplesRead == app.trialSamples)
            tStart = tic;
            app.lbj.verbose = 0;
            stopStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = RTDataState.dataIdle;
            app.taskState = RTTaskState.taskEndtrial;
          end
      end
    end
    
    function doClear(app, ~)
      clearAll(app.saccades, app);
      for i = 1:app.numTrialTypes
          clearAll(app.rtDists{i}, app);
      end
      clearTraces(app);
      app.fixOffTimeS = 0;                                      % stop plots from showing stale trial times
      app.targetTimeS = 0;
      doPlots(app.posVelPlots, app, 0, 0);
      app.calibrationText.Text = '';
      app.inFirstHalf = true;
      atTrialLimit(app);
    end
        
    function doLoadData(app, fileName, pathName)
      tMode = app.taskMode;                               % keep taskMode across data loads
      load([pathName fileName], 'saccData', 'gapDist', 'stepDist', 'appData');
      app.taskMode = tMode;                               % keep taskMode across data loads
      app.calTrialsDone = appData.calTrialsDone;
      app.numSummed = appData.numSummed;
      app.posAvg = appData.velAvg;
      app.posSummed = appData.posSummed;
      app.posTrace = appData.posTrace;
      app.rawData = appData.rawData;
      app.saccadeSamples = appData.saccadeSamples;

      app.saccStartIndex = appData.saccStartIndex;
      app.saccEndIndex = appData.saccEndIndex;
      app.stepSign = appData.stepSign;
      app.stepSizeDeg = appData.stepSizeDeg;
      app.targetTimeS = appData.targetTimeS;
      app.trialSamples = appData.trialSamples;
      app.trialType = appData.trialType;
      app.trialTypesDone = appData.trialTypesDone;
      app.velAvg = appData.velAvg;
      app.velSummed = appData.velSummed;
      app.velTrace = appData.velTrace;
      app.viewDistanceText.Value = sprintf('%d', appData.viewDistance);
      app.thresholdDegText.Value = sprintf('%d', appData.thresholdDeg);
      app.thresholdDPSText.Value = sprintf('%d', appData.thresholdDPS);
      app.stopAfterText.Value = sprintf('%d', appData.stopAfter);
      app.PositionButton.Value = appData.positionButtonValue;
      app.SpeedButton.Value = appData.speedButtonValue;
      app.saccades = saccData;
      app.calibrationText.Text = sprintf('Calibration %.1f deg/V', app.saccades.degPerV);
      app.rtDists{app.kGapTrial} = gapDist;
      app.rtDists{app.kStepTrial} = stepDist;

      % saved axes handles generally aren't valid if we are in a new run.
      % Load the handles with the current axes handles.
      app.rtDists{app.kGapTrial}.fHandle = app.gapAxes;
      app.rtDists{app.kStepTrial}.fHandle = app.stepAxes;
      searchStartIndex = floor(app.targetTimeS * app.lbj.SampleRateHz);   % no saccades before stimon
      if app.taskMode == app.kTiming
        app.stepSign = -1;                                          % photodiode always driven negative
      end
      [app.saccStartIndex, app.saccEndIndex] = findSaccade(app.saccades, app, searchStartIndex);
      doPlots(app.posVelPlots, app, app.saccStartIndex, app.saccEndIndex);
      for i = app.kGapTrial:app.kStepTrial
        doPlots(app.rtDists{i});
      end
    end

    function [fileName, folderPath] = doSaveData(app, autoSave) 
      folderPath = '~/Desktop/RTData/Data/';
      if ~isfolder(folderPath)
        mkdir(folderPath);
      end
      timeString = string(datetime('now', 'Format', 'MMM-dd-HHmmss'));
      if nargin >= 2 && autoSave
        fileName = autoSaveName('RT', timeString);
      else
        fileName = sprintf('RT-%s', timeString);
      end
      gapDist = app.rtDists{app.kGapTrial};
      stepDist = app.rtDists{app.kStepTrial};
      saccData = app.saccades;
      appData.calTrialsDone = app.calTrialsDone;
      appData.numSummed = app.numSummed;
      appData.posAvg = app.velAvg;
      appData.posSummed = app.posSummed;
      appData.posTrace = app.posTrace;
      appData.rawData = app.rawData;
      appData.saccadeSamples = app.saccadeSamples;
      appData.saccStartIndex = app.saccStartIndex;
      appData.saccEndIndex = app.saccEndIndex;
      appData.stepSign = app.stepSign;
      appData.stepSizeDeg = app.stepSizeDeg;
      appData.targetTimeS = app.targetTimeS;
      appData.trialSamples = app.trialSamples;
      appData.trialType = app.trialType;
      appData.trialTypesDone = app.trialTypesDone;
      appData.velAvg = app.velAvg;
      appData.velSummed = app.velSummed;
      appData.velTrace = app.velTrace;
      appData.viewDistance = str2double(app.viewDistanceText.Value);
      appData.thresholdDeg = str2double(app.thresholdDegText.Value);
      appData.thresholdDPS = str2double(app.thresholdDPSText.Value);
      appData.stopAfter = str2double(app.stopAfterText.Value);
      appData.positionButtonValue = app.PositionButton.Value;
      appData.speedButtonValue = app.SpeedButton.Value;
      appData.thresholdTypeStr = app.ThresholdType.SelectedObject.Text;
      fprintf('Saving Date %s %s\n', folderPath, fileName);
      save(fullfile(folderPath, fileName), 'gapDist', 'stepDist', 'saccData', 'appData');
      % if we've succeeded, remove any backup versions
      fprintf(' Done Saving\n');
      if isfile([folderPath, 'temp.mat'])
        delete([folderPath, 'temp.mat']);
      end
    end

    function doSavePlots(app, ~)
      RTControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Data and Plots', ...
        'Message', 'Saving Data to ~/Desktop/RTData/Data', 'indeterminate', 'on');

      [fileName, pathName] = doSaveData(app, false); % save data
      fprintf('clearing\n');
      doClear(app);                                 % clear and reload (to clean up plots
      fprintf('loading data\n')
      doLoadData(app, fileName, pathName);

      % do not try to use exportgraphics with pdfs on the lab machines
      plotAxes = {app.posAxes, app.velAxes, app.stepAxes, app.gapAxes};
      plotLabels = {'Position', 'Velocity', 'Step', 'Gap'};
      for i = 1:length(plotLabels)
        fprintf('Saving %s Plot to ~/Desktop/RTData', plotLabels{i})
        progress.Message = sprintf('Saving %s Plot to ~/Desktop/RTData', plotLabels{i});
        if i == 1
          timeString = savePlot(plotAxes{i}, sprintf('~/Desktop/RTData/%s/', plotLabels{i}), 'RT', 'png');
        else
          savePlot(plotAxes{i}, sprintf('~/Desktop/RTData/%s/', plotLabels{i}), 'RT', 'png', timeString);
        end
      end
      fprintf('done saving');
      close(progress);
      RTControlState(app, 'on', {})
      fprintf('restored dialog');
    end
    
    function setupLabJack(app)
      %  get hardware info and do not continue if daq device/drivers unavailable
      
      app.lbj = labJackU6;                        % create the daq object
      open(app.lbj);                              % open connection to the daq
      if isempty(app.lbj.handle)
        originalSize = get(0, 'DefaultUIControlFontSize');
        set(0, 'DefaultUIControlFontSize', 14);
        questdlg('Exiting. Check USB connections?', 'No LabJack Device Found', 'OK', 'OK');
        set(0, 'DefaultUIControlFontSize', originalSize);
        return;
      else
        fprintf(1,'LabJack Ready.\n\n');
      end
      % create input channel list
      removeChannel(app.lbj, -1);                 % remove all input channels
      addChannel(app.lbj, 0, 10, ['s' 's']);      % add channel 0 as input
      app.lbj.SampleRateHz = 1000;                % sample rate (Hz)
      app.lbj.ResolutionADC = 1;                  % ADC resolution (AD bit depth)
      
      % configure LabJack for analog input streaming
      
      errorCode = streamConfigure(app.lbj);
      if errorCode > 0
        fprintf(1,'Reaction Time: Unable to configure LabJack. Error %d.\n', errorCode);
        return
      end
    end
    
  function taskController(app, ~, ~, ~)  
      persistent startTic;
    try
      switch app.taskState
        case RTTaskState.taskStarttrial
            if isempty(startTic)
                startTic = uint64(0);
            end
            fprintf('taskStarttrial %.1f\n', toc(startTic));
            startTic = tic;
            positionWindow(app.visStim);
            if sum(app.trialTypesDone) >= app.numTrialTypes         % finished another block
              app.trialTypesDone = zeros(1, app.numTrialTypes);    	% clear counters
              app.blocksDone = app.blocksDone + 1;                  % increment block counter
            end
            if atStepRangeLimit(app.visStim, app)
              app.trialType = app.kCenteringTrial;
            else
              app.trialType = ceil(rand() * app.numTrialTypes);     % randomly selected saccade step
              if app.trialType == app.kGapTrial
              elseif app.trialType == app.kStepTrial
              end
              offsetStartIndex = app.trialType;                            % mark the index where search starts
              while true                                             	% find an unused, doable step
                if app.trialTypesDone(app.trialType) == 0         % unused and doable
                  break;
                end
                app.trialType = mod(app.trialType, app.numTrialTypes) + 1; % try the next one
                assert(app.trialType ~= offsetStartIndex, 'SaccadeRT taskController: failed to find unused trial type');
              end
              if rand() > 0.5
                app.stepSign = app.kLeft;
              else
                app.stepSign = app.kRight;
              end
            end
            if app.taskMode == app.kTiming
              prepareTimingImages(app.visStim, app, app.trialType);
            else
              prepareImages(app.visStim, app);
            end
            if app.taskMode == app.kDebug
              app.voltage = min(5.0, app.visStim.currentOffsetPix / 1000.0);  % debugging - DACO0 should go to AIN0
              analogOut(app.lbj, 0, 2.5 + app.voltage);
            end
            app.trialStartTime = datetime('now');
            app.taskState = RTTaskState.taskSettle;                  	% go to settle state
            fprintf('   taskStartTrial done %.1f\n', toc(startTic));
        case RTTaskState.taskSettle
            fprintf('settle %.1f\n', toc(startTic));
          if seconds(datetime('now') - app.trialStartTime) > 0.015    % data settled for one taskTimer cycle
            app.prestimTimeS = app.prestimDurS + rand() * 0.125;	    % jitter the stimon time a bit
            if app.trialType == app.kGapTrial             	          % gap trials have target after the gap
              app.targetTimeS = app.prestimTimeS + app.gapDurS;
            else
              app.targetTimeS = app.prestimTimeS;              	      % all others have target before the gap
            end
            app.fixOffTimeS = app.prestimTimeS;
            app.samplesRead = 0;
            app.lbj.verbose = 0;
            error = startStream(app.lbj);
            app.trialStartTime = datetime('now');
            app.lbj.verbose = 1;
            app.dataState = RTDataState.dataCollect;
            app.taskState = RTTaskState.taskPrestim;              	  % go to prestim state
          end
        case RTTaskState.taskPrestim
            fprintf('taskPrestim %.1f\n', toc(startTic));
          trialTimeS = seconds(datetime('now') - app.trialStartTime);
          if trialTimeS > app.prestimTimeS	                          % preStim time up?
            if app.trialType == app.kCenteringTrial
              drawCenterStimulus(app.visStim, app);
            else
              drawImage(app.visStim, app.visStim.gapStim);
            end
            if app.trialType ~= app.kGapTrial           	            % gap trials have target after the gap
              app.targetTimeS = trialTimeS;
            end
            app.fixOffTimeS = trialTimeS;
            app.taskState = RTTaskState.taskGapstim;                  % go to gap state
          end
        case RTTaskState.taskGapstim
            fprintf('taskGapstim %.1f\n', toc(startTic));
          trialTimeS = seconds(datetime('now') - app.trialStartTime);
          if trialTimeS > app.prestimTimeS + app.gapDurS
            drawImage(app.visStim, app.visStim.finalStim);            % gap time up, draw postgap stim
            if app.trialType == app.kGapTrial                         % gap trials have target after the gap
              app.targetTimeS = trialTimeS;
            end
            app.taskState = RTTaskState.taskPoststim;               % collectData pushes us from poststim to endtrial
          end
            fprintf('   taskGapstim done %.1f\n', toc(startTic));
        case RTTaskState.taskEndtrial
            fprintf('taskEndtrial %.1f\n', toc(startTic));
          if app.trialType ~= app.kCenteringTrial          	% no updates needed on centering trials
            [app.saccStartIndex, app.saccEndIndex] = processSignals(app.saccades, app);
            if app.saccStartIndex > 0 && app.playSounds.Value
              sound(app.tone, app.audioFreqHz);
            end
            doPlots(app.posVelPlots, app, app.saccStartIndex, app.saccEndIndex);
            if app.saccStartIndex > 0
              addRT(app.rtDists{app.trialType}, (app.saccStartIndex / app.lbj.SampleRateHz  - app.targetTimeS) * 1000.0);
              if (app.trialType == app.kStepTrial)
                    loadStepTimesMS(app.rtDists{app.kGapTrial}, app.rtDists{app.kStepTrial}.reactTimesMS, ...
                            app.rtDists{app.kStepTrial}.n)
              end
            end
            thisXAxisMax = doPlots(app.rtDists{app.trialType});
            thoseXAxisMax = 0;
            for i = 1:app.numTrialTypes
              if i ~= app.trialType
                thoseXAxisMax = max(thoseXAxisMax, xAxisMax(app.rtDists{i}));
              end
            end
            if thisXAxisMax > thoseXAxisMax
              for i = 1:app.numTrialTypes
                if i ~= app.trialType
                  setXAxisMax(app.rtDists{i}, thisXAxisMax);
                end
              end
            elseif thisXAxisMax < thoseXAxisMax
              setXAxisMax(app.rtDists{app.trialType}, thoseXAxisMax);
            end
            app.calibrationText.Text = sprintf('Calibration %.0f mV/deg', 1000.0 / app.saccades.degPerV);
          end
          app.taskState = RTTaskState.taskStarttrial;
          if atTrialLimit(app)
            startButton_Callback(app);                      % stop if we've hit the specified trial limit
          elseif app.rtDists{app.kStepTrial}.n > 0 && ~mod(app.rtDists{app.kStepTrial}.n, 10) && ...
              app.rtDists{app.kGapTrial}.n > 0 && ~mod(app.rtDists{app.kGapTrial}.n, 10)
            doSaveData(app, true);                          % autosave every 10 blocks when running
          end
        fprintf('   endTrial done %.1f\n', toc(startTic));

      end
    catch e
      fprintf(1,'\nError message: %s\n', e.message);
      fprintf(1,'Error identifier: %s',e.identifier);
      for s = 1:length(e.stack)
        fprintf('Stack %d: file %s name %s line %d\n', s, e.stack(s).file, e.stack(s).name, e.stack(s).line);
      end
      return;
    end

  end
        
  function taskError(~, ~, ~, ~)
    fprintf('timer error of some sort detected\n')
  end
        
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function openRT(app, varargin)
      fprintf(1,'SaccadeRT v2.5\n %s\n', string(datetime));
      set(app.figure1, 'visible', 'off');      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        if strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test')
          app.taskMode = app.kDebug;
        end
      else
        app.taskMode = app.kNormal;
      end
      %     taskMode = app.kTiming;
      switch app.taskMode
        case app.kDebug
          app.warnText.Text = 'Test Mode';
        case app.kTiming
          app.warnText.Text = 'Timing Test';
        otherwise
      end
      app.trialDurS = max(1.0, 2 * app.saccadeTraceS);
      app.prestimDurS = min(app.trialDurS / 4, 0.250);
      app.figure1.CloseRequestFcn = {@app.closeRT};                             % close function will close LabJack
      setupLabJack(app);
      if isempty(app.lbj.handle)
        app.delete;
        return;
      end

      [app.tone, app.audioFreqHz] = audioread(char('Tone4000.wav'));            % load the saccade tone

      app.saccades = RTSaccades(app);                                           % RTSaccades need LBJ set up first
      app.saccadeSamples = floor(app.saccadeTraceS * app.lbj.SampleRateHz);
      app.trialSamples = floor(app.trialDurS * app.lbj.SampleRateHz);
      clearTraces(app);

      app.visStim = RTStimulus(app);
      app.posVelPlots = RTPosVelPlots(app);
      axes = [app.gapAxes app.stepAxes];
      app.rtDists = cell(1, app.numTrialTypes);
      for i = 1:length(axes)
        app.rtDists{i} = RTDist(app, i, axes(i));
      end
      movegui(app.figure1, 'northeast');
      set(app.figure1, 'visible', 'on');      
        end

        % Button pushed function: clearButton
        function clearButton_Callback(app, event)
      selection = uiconfirm(app.figure1, 'Really clear all data? (This cannot be undone).', 'Clear Data');
      switch selection
        case 'OK'
          doClear(app, event);
      end
        end

        % Button pushed function: loadDataButton
        function loadDataButton_Callback(app, event)
      RTControlState(app, 'off', {})
      app.figure1.Visible = 'off';     % make sure uiputfile window is visible
      [fileName, pathName] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop');
      app.figure1.Visible = 'on';      % restore app window
      if fileName ~= 0
        doLoadData(app, fileName, pathName);
      end
      RTControlState(app, 'on', {})
        end

        % Button pushed function: saveDataButton
        function savePlotsButton_Callback(app, event)
      doSavePlots(app, event)
        end

        % Button pushed function: startButton
        function startButton_Callback(app, event)
      if strcmp(app.startButton.Text, 'Start') % if start button, do the following
        if atTrialLimit(app)
          return
        end
        viewDistanceCM = str2double(app.viewDistanceText.Value);
        if viewDistanceCM > app.maxViewDistCM
          errordlg(sprintf('Viewing distance must be <= %d cm', floor(app.maxViewDistCM)));
          return;
        end
        setViewDistanceCM(app.visStim, app, str2double(app.viewDistanceText.Value));
        app.saccades.thresholdDeg = str2double(app.thresholdDegText.Value);
        app.saccades.thresholdDPS = str2double(app.thresholdDPSText.Value);
        % create timer to control the task
        taskRateHz = 25;
        app.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0/taskRateHz, 'ErrorFcn', {@app.taskError}, 'TimerFcn',...
          {@app.taskController, [app.posAxes app.velAxes]});
        
        % create timer to get data from LabJack
        app.taskState = RTTaskState.taskStarttrial;
        app.trialStartTime = 0;
        app.dataState = RTDataState.dataIdle;
        dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
        app.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0 / dataCollectRateHz, 'ErrorFcn', {@app.taskError},...
          'TimerFcn', {@app.collectData, app}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
        
        % set the gui button to "running" state
        app.startButton.Text = 'Stop';
        app.startButton.BackgroundColor = 'red';
        RTControlState(app, 'off', {app.startButton})
        % save timers to handles and update the GUI display
        
        %% Start plots, data pickup, and data acquisition
        start(app.dataTimer);
        start(app.taskTimer);
        
        %% Stop -- we're already running, so it's a the stop button
      else % stop
        stop(timerfind);                                               	% stop/delete timers; pause data stream
        delete(timerfind);
        app.dataTimer = 0;
        app.taskTimer = 0;
        stopStream(app.lbj);
        doSaveData(app, true);
        app.startButton.Text = 'Start';
        app.startButton.BackgroundColor = 'green';
        RTControlState(app, 'on', {app.startButton})
        drawnow;
        drawCenterStimulus(app.visStim, app);                           	% recenter fixspot
      end
        end

        % Value changed function: stopAfterText
        function stopAfterTextValueChanged(app, event)
      value = atTrialLimit(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'NSCI 20100';
            app.posAxes = uiaxes(app.figure1);
            app.velAxes = uiaxes(app.figure1);
            app.gapAxes = uiaxes(app.figure1);
            app.stepAxes = uiaxes(app.figure1);
            app.startButton = uibutton(app.figure1, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
            app.startButton.Text = 'Start';
            app.clearButton = uibutton(app.figure1, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
            app.clearButton.Text = 'Clear Data';
            app.viewDistanceText = uieditfield(app.figure1, 'text');
            app.viewDistanceText.Value = '50';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Viewing Distance (cm):';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Threshold Speed (deg/s):';
            app.titleText = uilabel(app.figure1);
            app.titleText.Text = 'NSCI 20100 Reaction Time Laboratory';
            app.saveDataButton = uibutton(app.figure1, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
            app.saveDataButton.Text = 'Save Data';
            app.loadDataButton = uibutton(app.figure1, 'push');
            app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
            app.loadDataButton.Text = 'Load Data';
            app.warnText = uilabel(app.figure1);
            app.warnText.Text = '';
            app.calibrationText = uilabel(app.figure1);
            app.calibrationText.Text = '';
            app.Filter60Hz = uicheckbox(app.figure1);
            app.Filter60Hz.Text = 'Filter 60 Hz';
            app.Filter60Hz.Value = true;
            app.stopAfterText = uieditfield(app.figure1, 'text');
            app.stopAfterText.ValueChangedFcn = createCallbackFcn(app, @stopAfterTextValueChanged, true);
            app.stopAfterText.Value = '10';
            app.text2_2 = uilabel(app.figure1);
            app.text2_2.Text = 'Stop when n >=';
            app.limitText = uilabel(app.figure1);
            app.limitText.Text = '';
            app.ThresholdType = uibuttongroup(app.figure1);
            app.ThresholdType.Title = 'Threshold Type';
            app.PositionButton = uiradiobutton(app.ThresholdType);
            app.PositionButton.Text = 'Position';
            app.PositionButton.Value = true;
            app.SpeedButton = uiradiobutton(app.ThresholdType);
            app.SpeedButton.Text = 'Speed';
            app.thresholdDPSText = uieditfield(app.figure1, 'text');
            app.thresholdDPSText.Value = '150';
            app.thresholdDegText = uieditfield(app.figure1, 'text');
            app.thresholdDegText.Value = '3';
            app.text3_2 = uilabel(app.figure1);
            app.text3_2.Text = 'Threshold Position (deg):';
            app.playSounds = uicheckbox(app.figure1);
            app.playSounds.Text = 'Play Sounds';
            app.playSounds.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaccadeRT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)openRT(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end