classdef SaccadeRT_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1           matlab.ui.Figure
    axes1             matlab.ui.control.UIAxes
    axes3             matlab.ui.control.UIAxes
    axes6             matlab.ui.control.UIAxes
    axes7             matlab.ui.control.UIAxes
    axes8             matlab.ui.control.UIAxes
    startButton       matlab.ui.control.Button
    clearButton       matlab.ui.control.Button
    viewDistanceText  matlab.ui.control.EditField
    text2             matlab.ui.control.Label
    thresholdDegText  matlab.ui.control.EditField
    text3             matlab.ui.control.Label
    savePlotsButton   matlab.ui.control.Button
    titleText         matlab.ui.control.Label
    saveDataButton    matlab.ui.control.Button
    loadDataButton    matlab.ui.control.Button
    warnText          matlab.ui.control.Label
    calibrationText   matlab.ui.control.Label
    Filter60Hz        matlab.ui.control.CheckBox
  end

    
  methods (Access = private)
    function closeRT(app, hObject, eventdata, handles)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % fist check whether the task is running
      if strcmp(get(handles.startButton, 'String'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try delete(handles.visStim); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('handles.lbj'); catch, end
          delete(app.figure1);                                                            % close the program window
        case 'Cancel'
          return
      end
    end
            
    function collectData(app, obj, event)
      % reads stream data from the LabJack
      handles = obj.UserData;
      data = handles.data;
      lbj = handles.lbj;                                                     % obj.UserData is pointer to handles
      switch data.dataState
        case RTDataState.dataIdle
          return;
        case RTDataState.dataCollect
          [dRaw, ~] = getStreamData(lbj);                                     %#ok<*NASGU> % get stream data
          numNew = min(length(dRaw), data.trialSamples - data.samplesRead);
          data.rawData(data.samplesRead + 1:data.samplesRead + numNew, :) = dRaw(1:numNew, :);
          data.samplesRead = data.samplesRead + numNew;
          if (data.samplesRead == data.trialSamples)
            tStart = tic;
            handles.lbj.verbose = 0;
            stopStream(handles.lbj);
            handles.lbj.verbose = 1;
            data.dataState = RTDataState.dataIdle;
            data.taskState = RTTaskState.taskEndtrial;
          end
      end
    end
        
        function lbj = setupLabJack(app)
            %  get hardware info and do not continue if daq device/drivers unavailable
            
                lbj = labJackU6;                        % create the daq object
                open(lbj);                              % open connection to the daq
                if isempty(lbj.handle)
                    originalSize = get(0, 'DefaultUIControlFontSize');
                    set(0, 'DefaultUIControlFontSize', 14);
                    questdlg('Exit and check USB connections.', ...
                        'No LabJack Device Found', 'OK', 'OK');
                    set(0, 'DefaultUIControlFontSize', originalSize);
                else
                    fprintf(1,'Reaction Time: LabJack Ready.\n\n');
                end
                % create input channel list
                removeChannel(lbj, -1);                     % remove all input channels
                addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
                lbj.SampleRateHz = 1000;                    % sample rate (Hz)
                lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
            
                % configure LabJack for analog input streaming
            
                errorCode = streamConfigure(lbj);
                if errorCode > 0
                    fprintf(1,'Reaction Time: Unable to configure LabJack. Error %d.\n',errorCode);
                    return
                end
        end
        
  function taskController(app, obj, events, daqaxes)
    
    handles = obj.UserData;
    data = handles.data;
    visStim = handles.visStim;
    saccades = handles.saccades;
    rtDists = handles.rtDists;
    switch data.taskState
      case RTTaskState.taskStarttrial
        positionWindow(visStim);
        if sum(data.trialTypesDone) >= data.numTrialTypes          	% finished another block
          data.trialTypesDone = zeros(1, data.numTrialTypes);    	% clear counters
          data.blocksDone = data.blocksDone + 1;                  % increment block counter
        end
        if atStepRangeLimit(visStim)
          data.trialType = RTConstants.kCenteringTrial;
        else
          data.trialType = ceil(rand() * data.numTrialTypes);     % randomly selected saccade step
          startIndex = data.trialType;                            % mark the index where search starts
          while true                                             	% find an unused, doable step
            if data.trialTypesDone(data.trialType) == 0         % unused and doable
              break;
            end
            data.trialType = mod(data.trialType, data.numTrialTypes) + 1; % try the next one
            assert(data.trialType ~= startIndex, 'SaccadeRT taskController: failed to find unused trial type');                     % no types available (shouldn't happen)
          end
          if rand() > 0.5
            data.stepDirection = RTConstants.kLeft;
          else
            data.stepDirection = RTConstants.kRight;
          end
        end
        if data.taskMode == RTConstants.kTiming
          prepareTimingImages(visStim, data.trialType);
        else
          prepareImages(visStim, data.trialType, data.stepDirection);
        end
        if data.taskMode == RTConstants.kDebug
          data.voltage = min(5.0, visStim.currentOffsetPix / 1000.0);  % debugging - DACO0 should go to AIN0
          analogOut(handles.lbj, 0, 2.5 + data.voltage);
        end
        data.trialStartTimeVec = clock;                          	% get a valid start for settle time
        data.taskState = RTTaskState.taskSettle;                  	% go to settle state
      case RTTaskState.taskSettle
        if etime(clock, data.trialStartTimeVec) > 0.015               % data settled for one taskTimer cycle
          data.prestimTimeS = data.prestimDurS + rand() * 0.125;	% jitter the stimon time a bit
          if data.trialType == RTConstants.kGapTrial             	% gap trials have target after the gap
            data.targetTimeS = data.prestimTimeS + data.gapDurS;
          else
            data.targetTimeS = data.prestimTimeS;              	% all others have target before the gap
          end
          if data.trialType == RTConstants.kOverlapTrial        	% overlap trials have fixOff after the gap
            data.fixOffTimeS = data.prestimTimeS + data.gapDurS;
          else                                                    % all others have fixOff before the gap
            data.fixOffTimeS = data.prestimTimeS;
          end
          data.samplesRead = 0;
          handles.lbj.verbose = 0;
          startStream(handles.lbj);
          data.trialStartTimeVec = clock;                      	% reset the trial clock to data collection
          handles.lbj.verbose = 1;
          data.dataState = RTDataState.dataCollect;
          data.taskState = RTTaskState.taskPrestim;              	% go to prestim state
        end
      case RTTaskState.taskPrestim
        trialTimeS = etime(clock, data.trialStartTimeVec);
        if trialTimeS > data.prestimTimeS	% preStim time up?
          if data.trialType == RTConstants.kCenteringTrial
            drawCenterStimulus(visStim);
          else
            drawImage(visStim, visStim.gapStim);
          end
          if data.trialType ~= RTConstants.kGapTrial           	% gap trials have target after the gap
            data.targetTimeS = trialTimeS;
          end
          if data.trialType ~= RTConstants.kOverlapTrial       	% overlap trials have fixoff after the gap
            data.fixOffTimeS = trialTimeS;
          end
          data.taskState = RTTaskState.taskGapstim;               % go to gap state
        end
      case RTTaskState.taskGapstim
        trialTimeS = etime(clock, data.trialStartTimeVec);
        if trialTimeS > data.prestimTimeS + data.gapDurS
          drawImage(visStim, visStim.finalStim);                 	% gap time up, draw postgap stim
          if data.trialType == RTConstants.kGapTrial              % gap trials have target after the gap
            data.targetTimeS = trialTimeS;
          end
          if data.trialType == RTConstants.kOverlapTrial       	% overlap trials have fixoff after the gap
            data.fixOffTimeS = trialTimeS;
          end
          data.taskState = RTTaskState.taskPoststim;
        end
      case RTTaskState.taskEndtrial
        fprintf('taskEndtrial 0\n');
        if data.trialType ~= RTConstants.kCenteringTrial          	% no updates needed on centering trials
          fprintf('taskEndtrial 0.0\n');
          [startIndex, endIndex] = processSignals(saccades, data);
          fprintf('taskEndtrial 0.1\n');
          plot(handles.posVelPlots, handles, startIndex, endIndex);
          fprintf('taskEndtrial 0.2\n');
          if startIndex > 0
            addRT(rtDists{data.trialType}, (startIndex / data.sampleRateHz  - data.targetTimeS) * 1000.0);
          end
        fprintf('taskEndtrial 1\n');
          if (mod(sum(data.numSummed), data.numTrialTypes) == 0)	% finished a block
                fprintf('taskEndtrial before plot\n');
            needsRescale = plot(rtDists{data.trialType});
                fprintf('taskEndtrial plot\n');
            if needsRescale > 0
              for i = 1:data.numTrialTypes
                rescale(rtDists{i}, needsRescale);
                plot(rtDists{i});
              end
            end
          end
          set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', saccades.degPerV));
        end
        data.trialStartTimeVec = [];                                % why do we need to clear this?
        data.taskState = RTTaskState.taskStarttrial;
   end
  end
        
  function taskError(~, ~, ~, ~)
    fprintf('timer error\n')
  end
        
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openRT(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to RT (see VARARGIN)
            
                % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
                if ~isempty(varargin)
                    if strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test')
                        taskMode = RTConstants.kDebug;
                    end
                else
                    taskMode = RTConstants.kNormal;
                end
            
            %     taskMode = RTConstants.kTiming;
                taskMode = RTConstants.kDebug;
            
                switch taskMode
                    case RTConstants.kDebug
                        set(handles.warnText, 'string', 'Debug Mode');
                    case RTConstants.kTiming
                        set(handles.warnText, 'string', 'Timing Test');
                    otherwise
                end
                handles.output = hObject;                                               % select default command line output
                handles.saccades = RTSaccades;
                set(hObject, 'CloseRequestFcn', {@app.closeRT, handles});                  % close function will close LabJack
                handles.lbj = setupLabJack(app);
                handles.data = RTTaskData(handles.lbj.numChannels, handles.lbj.SampleRateHz);
                handles.visStim = RTStimulus(handles.data.stepSizeDeg);
                handles.posVelPlots = RTPosVelPlots(handles);
            %     handles.ampDur = RTAmpDur(handles.axes5, handles.data.offsetsDeg, handles.lbj.SampleRateHz);
                axes = [handles.axes6 handles.axes7 handles.axes8];
                handles.RTDist = cell(1, handles.data.numTrialTypes);
                for i = 1:length(axes)
                    handles.rtDists{i} = RTDist(i, axes(i));
                end
            
                % set up test mode
                handles.data.taskMode = taskMode;
            %     if (handles.data.taskMode == RTConstants.kDebug)
            %         analogOut(handles.lbj, 0, 2.5);                                     % For debugging (AOuts to AIns)
            %     end
                movegui(hObject, 'northeast');
                guidata(hObject, handles);                                              % save the selection
    end

    % Value changed function: Filter60Hz
    function Filter60Hz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                set60HzFilter(handles.data, get(hObject,'Value'));
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      selection = uiconfirm(app.figure1, 'Really clear all data? (This cannot be undone).', 'Clear Data');
      switch selection
        case 'OK'
          clearAll(handles.saccades);
          for i = 1:handles.data.numTrialTypes
              clearAll(handles.rtDists{i});
          end
          clearAll(handles.data);
          handles.data.fixOffTimeS = 0;                       % stop plots from showing stale trial times
          handles.data.targetTimeS = 0;
          plot(handles.posVelPlots, handles, 0, 0);
          set(handles.calibrationText, 'string', '');
          guidata(hObject, handles);
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      %
      RTControlState(handles, 'off', {})
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop');
      if fileName ~= 0
        taskMode = handles.data.taskMode;                               % keep taskMode across data loads
        load([filePath fileName], 'd', 's', 'r1', 'r2', 'r3');
        handles.data = d;
        handles.data.taskMode = taskMode;                               % keep taskMode across data loads
        handles.saccades = s;
        set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', handles.saccades.degPerV));
        handles.rtDists{1} = r1;
        handles.rtDists{2} = r2;
        handles.rtDists{3} = r3;

        % saved axes handles generally aren't valid if we are in a new run.
        % Load the handles with the current axes handles.
        handles.rtDists{1}.fHandle = handles.axes6;
        handles.rtDists{2}.fHandle = handles.axes7;
        handles.rtDists{3}.fHandle = handles.axes8;
        guidata(hObject, handles);                                      % save the selections

        [startIndex, endIndex] = processSignals(handles.saccades, d);
        plot(handles.posVelPlots, handles, startIndex, endIndex);
        for i = RTConstants.kGapTrial:RTConstants.kOverlapTrial
          plot(handles.rtDists{i});
        end
      end
      RTControlState(handles, 'on', {})
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % Saving the workspace for a GUI isn't simple.  What we have accessible in this
      % environment is mostly the handles.  If we save, it's an attempt to save
      % handles, which doesn't work.  Instead, we get a list of all the properties
      % from the RTTaskData class, and then use eval statement to assign those to
      % local variable and save them (one by one).
      RTControlState(handles, 'off', {})
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/RTData.mat');
      if fileName ~= 0
        d = handles.data;
        s = handles.saccades;
        r1 = handles.rtDists{1};
        r2 = handles.rtDists{2};
        r3 = handles.rtDists{3};
        save([filePath fileName], 'd', 's', 'r1', 'r2', 'r3');
      end
      RTControlState(handles, 'on', {})
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                RTControlState(handles, 'off', {})
                [fileName, filePath] = uiputfile('*.pdf', 'Save Window Plots as PDF', '~/Desktop/RTPlots.pdf');
                if fileName ~= 0
                    set(handles.figure1, 'PaperUnits', 'inches');
                    figurePos = get(handles.figure1, 'position');
                    widthInch = figurePos(3) / 72;
                    heightInch = figurePos(4) / 72;
                    set(handles.figure1, 'inverthardcopy', 'off');                  % keep gray background
                    set(handles.figure1, 'PaperOrientation', 'landscape');
                    set(handles.figure1, 'PaperSize', [widthInch + 1.0, heightInch + 1.0]);
                    set(handles.figure1, 'PaperPosition', [0.5, 0.5, widthInch, heightInch]);
                    print(handles.figure1, '-dpdf', '-r600', [filePath fileName]);
                end
                RTControlState(handles, 'on', {})
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                if strcmp(get(handles.startButton, 'String'), 'Start') % if start button, do the following
                    fprintf(1,'\nReaction Time v1.0\n %s\n', datestr(clock));
                    viewDistanceCM = str2double(get(handles.viewDistanceText, 'string'));
                    if viewDistanceCM > RTConstants.maxViewDistCM
                        errordlg(sprintf('Viewing distance must be <= %d cm', floor(RTConstants.maxViewDistCM)));
                        return;
                    end
                    setViewDistanceCM(handles.visStim, str2double(get(handles.viewDistanceText, 'string')));
                    handles.saccades.thresholdDeg = str2double(get(handles.thresholdDegText, 'string'));
                    % create timer to control the task
                    taskRateHz = 25;
                    handles.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate',...
                        'Period', 1.0/taskRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles}, 'TimerFcn',...
                        {@app.taskController, [handles.axes1 handles.axes3]});
            
                    % create timer to get data from LabJack
                    handles.data.taskState = RTTaskState.taskStarttrial;
                    handles.data.trialStartTimeVec = [];
                    handles.data.dataState = RTDataState.dataIdle;
                    dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
                    handles.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate',...
                        'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles},...
                        'TimerFcn', {@app.collectData}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
            
                    % set the gui button to "running" state
                    set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
                    RTControlState(handles, 'off', {handles.startButton})
                    % save timers to handles and update the GUI display
            
                    %% Start plots, data pickup, and data acquisition
                    start(handles.dataTimer);
                    start(handles.taskTimer);
            
                %% Stop -- we're already running, so it's a the stop button
                else % stop
                    stop(timerfind);                                               	% stop/delete timers; pause data stream
                    delete(timerfind);
                    handles.dataTimer = 0;
                    handles.taskTimer = 0;
                    stopStream(handles.lbj);
                    set(handles.startButton, 'string', 'Start','backgroundColor', 'green');
                    RTControlState(handles, 'on', {handles.startButton})
                    drawnow;
                    drawCenterStimulus(handles.visStim);                           	% recenter fixspot
                end
                guidata(hObject, handles);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.Tag = 'axes1';
      app.axes3 = uiaxes(app.figure1);
      app.axes3.Tag = 'axes3';
      app.axes6 = uiaxes(app.figure1);
      app.axes6.Tag = 'axes6';
      app.axes7 = uiaxes(app.figure1);
      app.axes7.Tag = 'axes7';
      app.axes8 = uiaxes(app.figure1);
      app.axes8.Tag = 'axes8';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.viewDistanceText = uieditfield(app.figure1, 'text');
      app.viewDistanceText.Tag = 'viewDistanceText';
      app.viewDistanceText.Value = '50';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Viewing Distance (cm):';
      app.thresholdDegText = uieditfield(app.figure1, 'text');
      app.thresholdDegText.Tag = 'thresholdDegText';
      app.thresholdDegText.Value = '3';
      app.text3 = uilabel(app.figure1);
      app.text3.Tag = 'text3';
      app.text3.Enable = 'off';
      app.text3.Text = 'Saccade Threshold (deg):';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Reaction Time Laboratory';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.calibrationText = uilabel(app.figure1);
      app.calibrationText.Tag = 'calibrationText';
      app.calibrationText.Text = '';
      app.Filter60Hz = uicheckbox(app.figure1);
      app.Filter60Hz.ValueChangedFcn = createCallbackFcn(app, @Filter60Hz_Callback, true);
      app.Filter60Hz.Tag = 'Filter60Hz';
      app.Filter60Hz.Text = 'Filter 60 Hz';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SaccadeRT_App(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openRT(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end