classdef Metrics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        statsTable        matlab.ui.control.Table
        fitTable          matlab.ui.control.Table
        ThresholdType     matlab.ui.container.ButtonGroup
        SpeedButton       matlab.ui.control.RadioButton
        PositionButton    matlab.ui.control.RadioButton
        text3_2           matlab.ui.control.Label
        limitText         matlab.ui.control.Label
        text2_2           matlab.ui.control.Label
        stopAfterText     matlab.ui.control.EditField
        Filter60Hz        matlab.ui.control.CheckBox
        calibrationText   matlab.ui.control.Label
        warnText          matlab.ui.control.Label
        loadDataButton    matlab.ui.control.Button
        saveDataButton    matlab.ui.control.Button
        titleText         matlab.ui.control.Label
        savePlotsButton   matlab.ui.control.Button
        text3             matlab.ui.control.Label
        thresholdDegText  matlab.ui.control.EditField
        thresholdDPSText  matlab.ui.control.EditField
        text2             matlab.ui.control.Label
        viewDistanceText  matlab.ui.control.EditField
        clearButton       matlab.ui.control.Button
        startButton       matlab.ui.control.Button
        ampDurAxes        matlab.ui.control.UIAxes
        avgVelAxes        matlab.ui.control.UIAxes
        velAxes           matlab.ui.control.UIAxes
        avgPosAxes        matlab.ui.control.UIAxes
        posAxes           matlab.ui.control.UIAxes
    end


  properties (Access = public)
    absStepIndex = 1;       % index of current step, accounting for sign
    ampDur;                 % handle for MetricsAmpDur object
    blocksDone = 0;         % number of blocks completed
    calTrialsDone = 0;      % number of calibration trials done
    centeringTrial = false; % flag for centering trial
    dataState = MetricsDataState.dataIdle;  % state of data collection system
    dataTimer               % timer for data collection
    doStimDisplay = true;   % do display or run fast (for test mode)
    fits;                   % MetricsFits handle
    folderPath = '~/Desktop/MData/' % destination for saved files
    lbj;                    % handle to LabJack controller object
    medians;                % median saccade durations
    numChannels;            % number of analog channels
    numOffsets;             % number of offsets used
    numSummed;              % number of traces averaged
    offsetsDeg = [4, 8, 12, 16, 20, -4, -8, -12, -16, -20]; % offsets to test
    offsetsDone;            % counts for of offsets done
    offsetIndex = 1;        % index for the current step offset
    posAvg;                 % trace with the average position
    posSummed;              % counts of number of position traces summed
    posTrace;               % filtered position trace
    posVelPlots             % handle for MetricsPosVelPlots object
    prestimDurS;            % duration of the prestim period
    rawData;                % raw trial samples from LabJack
    saccades;               % MetricsSaccades handle
    saccadeSamples;         % number of samples in the saccade trace
    saccadeTraceS = 0.250;  % duratoin of saccade trace
    samplesRead = 0;        % count of samples read this trial
    stepSign = 1;           % sign of current step (1 or -1)
    stepSizeDeg = 0;        % size of the step on the current trial
    stimTimeS = 0;          % time of stimulus in each trial
    stopAtTrialEnd = 0;     % flag to stop at end of current trial
    taskState = MetricsTaskState.taskStarttrial; % current task state
    taskTimer;              % timer for controlling task states
    testMode = false;       % set this up in openMetrics()
    trialSamples;           % A/D samples in a trial
    trialStartTime;         % start of current trial
    velAvg;                 % average velocity trace
    velSummed;              % counts of number of velocity traces summed
    velTrace;               % filtered velocity trace
    visStim;                % handle for MetricsStimulus object
    voltage = 0;            % current voltage settign for LabJack D/A
  end
  
  properties (Access = private)
    saccStartIndex = 0;     % trace index for most recent saccade
    saccEndIndex = 0;       % trace index for most recent saccade
    trialCounter = 0;
  end

  methods (Access = private)

    function atLimit = atTrialLimit(app)
      atLimit = app.blocksDone >= str2double(app.stopAfterText.Value);
      if atLimit
        app.limitText.Text = 'At requested n';
      else
        app.limitText.Text = '';
      end
    end

    function closeMetrics(app, ~, ~, ~)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % fist check whether the task is running
      if strcmp(app.startButton.Text, 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          app.lbj.verbose = 0;
          try cleanup(app.visStim); catch, end
          try delete(app.visStim); catch, end
          try delete(app.ampDur); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                % close the program window
        case 'Cancel'
          return
      end
    end

    function clearBuffers(app)
      app.blocksDone = 0;
      app.calTrialsDone = 0;
      app.medians = zeros(1, app.numOffsets);
      app.numSummed = zeros(1, app.numOffsets);
      app.offsetsDone = zeros(1, app.numOffsets);
      app.rawData = zeros(app.trialSamples, app.numChannels);     % raw data
      app.posTrace = zeros(app.trialSamples, 1);                  % trial EOG position trace
      app.posSummed = zeros(app.saccadeSamples, app.numOffsets);  % summed position traces
      app.posAvg = zeros(app.saccadeSamples, app.numOffsets);     % averaged position traces
      app.velTrace = zeros(app.trialSamples, 1);                      % trial EOG velocity trace
      app.velSummed = zeros(app.saccadeSamples, app.numOffsets);  % summed position traces
      app.velAvg = zeros(app.saccadeSamples, app.numOffsets);     % averaged position traces
    end

    function collectData(app, ~, ~, ~)
      % reads stream data from the LabJack
      switch app.dataState
        case MetricsDataState.dataIdle
          return;
        case MetricsDataState.dataCollect
          [dRaw, ~] = getStreamData(app.lbj);                                     %#ok<*NASGU> % get stream data
          numNew = min(length(dRaw), app.trialSamples - app.samplesRead);
          app.rawData(app.samplesRead + 1:app.samplesRead + numNew, :) = dRaw(1:numNew, :);
          app.samplesRead = app.samplesRead + numNew;
          if (app.samplesRead == app.trialSamples)
            tStart = tic;
            app.lbj.verbose = 0;
            stopStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = MetricsDataState.dataIdle;
            app.taskState = MetricsTaskState.taskEndtrial;
          end
      end
    end

    function fileName = doSaveData(app, autoSave)
      fPath = [app.folderPath, 'Data/'];
      if ~isfolder(fPath)
        mkdir(fPath);
      end
      if nargin >= 2 && autoSave
        fileName = 'MT-Autosave';
        savePath = [fPath, fileName, '.mat'];
        if isfile(savePath)
          movefile(savePath, [fPath, 'temp.mat']);
        end
      else
        fileName = sprintf('MT-%s', string(datetime('now', 'Format', 'MMM-dd-HHmmss')));
      end
      a = app.ampDur;
      s = app.saccades;
      appData.blocksDone = app.blocksDone;
      appData.calTrialsDone = app.calTrialsDone;
      appData.numSummed = app.numSummed;
      appData.offsetsDone = app.offsetsDone;
      appData.rawData = app.rawData;
      appData.posTrace = app.posTrace;
      appData.posSummed = app.posSummed;
      appData.posAvg = app.posAvg;
      appData.saccStartIndex = app.saccStartIndex;
      appData.saccEndIndex = app.saccEndIndex;
      appData.velTrace = app.velTrace;
      appData.velSummed = app.velSummed;
      appData.velAvg = app.velAvg;
      appData.viewDistance = str2double(app.viewDistanceText.Value);
      appData.thresholdDeg = str2double(app.thresholdDegText.Value);
      appData.thresholdDPS = str2double(app.thresholdDPSText.Value);
      appData.stopAfter = str2double(app.stopAfterText.Value);
      appData.positionButtonValue = app.PositionButton.Value;
      appData.speedButtonValue = app.SpeedButton.Value;
      appData.thresholdTypeStr = app.ThresholdType.SelectedObject.Text;
      save(fullfile(fPath, fileName), 'a', 's', 'appData');
      if isfile([fPath, 'temp.mat'])
        delete([fPath, 'temp.mat']);
      end
    end

    function freshAxes(app)
      % an attempt to purge uiaxes overheah that bogs down the system
      delete(app.posAxes);
      app.posAxes = uiaxes(app.figure1, 'Position', [42, 594, 325, 200]);
%       app.posAxes.Toolbar.Visible = 'off';
%       app.posAxes.Interactions = [];
%       disableDefaultInteractivity(app.posAxes);
      delete(app.velAxes);
      app.velAxes = uiaxes(app.figure1, 'Position', [42, 368, 325, 200]);
%       app.velAxes.Toolbar.Visible = 'off';
%       app.velAxes.Interactions = [];
%       disableDefaultInteractivity(app.velAxes);
      delete(app.avgPosAxes);
      app.avgPosAxes = uiaxes(app.figure1, 'Position', [442, 594, 325, 200]);
%       app.avgPosAxes.Toolbar.Visible = 'off';
%       app.avgPosAxes.Interactions = [];
%       disableDefaultInteractivity(app.avgPosAxes);
      delete(app.avgVelAxes);
      app.avgVelAxes = uiaxes(app.figure1, 'Position', [445, 368, 325, 200]);
%       app.avgVelAxes.Toolbar.Visible = 'off';
%       app.avgVelAxes.Interactions = [];
%       disableDefaultInteractivity(app.avgVelAxes);
      delete(app.ampDurAxes);
      app.ampDurAxes = uiaxes(app.figure1, 'Position', [445, 132, 325, 200]);
%       app.ampDurAxes.Toolbar.Visible = 'off';
%       app.ampDurAxes.Interactions = [];
%       disableDefaultInteractivity(app.ampDurAxes);
      fprintf('freshAxes: refreshed figure axes\n')
    end

    function lbj = setupLabJack(app)
      %  get hardware info and do not continue if daq device/drivers unavailable

      lbj = labJackU6;                        % create the daq object
      open(lbj);                              % open connection to the daq
      if isempty(lbj.handle)
        set(app.figure1, 'visible', 'on');
        selection = uiconfirm(app.figure1, 'No LabJack device found.  Check USB connections?', 'Fatal Error', ...
          'Icon', 'error', 'options', {'Exit'});
        set(app.figure1, 'visible', 'off');
        lbj = [];
        return;
      else
        fprintf(1,'Metrics: LabJack Ready.\n\n');
      end
      % create input channel list
      removeChannel(lbj, -1);                     % remove all input channels
      addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
      lbj.SampleRateHz = 1000;                    % sample rate (Hz)
      lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)

      % configure LabJack for analog input streaming

      errorCode = streamConfigure(lbj);
      if errorCode > 0
        fprintf(1,'Metrics: Unable to configure LabJack. Error %d.\n', errorCode);
        return
      end
    end

    function taskController(app, ~, ~, ~)
      switch app.taskState
        case MetricsTaskState.taskStarttrial
          positionWindow(app.visStim);                            % make sure stim window is positioned correctly
          app.offsetIndex = ceil(rand() * app.numOffsets);        % randomly selected saccade step
          offsetStartIndex = app.offsetIndex;                           % mark the index where search starts
          app.centeringTrial = false;
          while true                                              % find an unused, doable step
            if app.offsetsDone(app.offsetIndex) == 0 && ...       % unused and doable
                ~stepOutOfRange(app.visStim, app.offsetsDeg(app.offsetIndex))
              break;
            end
            app.offsetIndex = mod(app.offsetIndex, app.numOffsets) + 1; % try the next one
            if app.offsetIndex == offsetStartIndex                % no steps doable from current position
              app.centeringTrial = true;                          % center the stimulus and try again
              break;
            end
          end
          app.absStepIndex = mod(app.offsetIndex - 1, app.numOffsets / 2) + 1;
          app.stepSign = sign(app.offsetsDeg(app.offsetIndex));
          app.stepSizeDeg = app.offsetsDeg(app.absStepIndex);
          if app.testMode                                         	% debugging- connect DACO0 to AIN0
            app.voltage = min(5.0, app.visStim.currentOffsetDeg / 1000.0);
            analogOut(app.lbj, 0, 2.5 + app.voltage);
          end
          app.trialStartTime = datetime('now');
          app.taskState = MetricsTaskState.taskSettle;              % go to settle state
        case MetricsTaskState.taskSettle
          if seconds(datetime('now') - app.trialStartTime) > 0.015  % data settled for one taskTimer cycle
            app.trialStartTime = datetime('now');
            app.stimTimeS = app.prestimDurS + rand() * 0.125;   	  % jitter the stimon time a bit
            app.samplesRead = 0;
            app.lbj.verbose = 0;
            startStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = MetricsDataState.dataCollect;
            app.taskState = MetricsTaskState.taskPrestim;         % go to prestim state
          end
        case MetricsTaskState.taskPrestim
          if seconds(datetime('now') - app.trialStartTime) > app.stimTimeS  % data settled for one taskTimer cycle
            if app.centeringTrial
              centerStimulus(app.visStim);
            else
              stepStimulus(app.visStim, app.offsetsDeg(app.offsetIndex));
            end
            app.taskState = MetricsTaskState.taskPoststim;
          end
        case MetricsTaskState.taskPoststim
        case MetricsTaskState.taskEndtrial
          if ~app.centeringTrial
            [app.saccStartIndex, app.saccEndIndex] = processSignals(app.saccades, app);
            if app.saccStartIndex > 0                                     % successful trial completion
              if (app.offsetsDone(app.offsetIndex) > 0)
                printf('ERROR: taskTimer: offset %d is already %d, not incrementing', ...
                  app.offsetIndex, app.offsetsDone(app.offsetIndex))
              end
              app.offsetsDone(app.offsetIndex) = app.offsetsDone(app.offsetIndex) + 1;
              if sum(app.offsetsDone == 0) == 0                   % finished another block
                app.blocksDone = app.blocksDone + 1;              % increment block counter
                app.offsetsDone = zeros(1, app.numOffsets);       % clear counters
              end
            end
            blockDone = addAmpDur(app.ampDur, app, app.saccStartIndex, app.saccEndIndex);
            if blockDone
              doFits(app.fits, app);  
              doSaveData(app, true);
              freshAxes(app);
              plotAmpDur(app.ampDur, app);
              drawnow;
            end
            plotPosVel(app.posVelPlots, app, app.saccStartIndex, app.saccEndIndex, blockDone);
            app.calibrationText.Text = sprintf('Calibration %.0f mV/deg', 1000.0 / app.saccades.degPerV);
          end
          app.trialStartTime = 0;
          app.taskState = MetricsTaskState.taskStarttrial;
          % stop data collection if we've hit the specified trial limit or been requested to stop
          if atTrialLimit(app) || app.stopAtTrialEnd
            stopTask(app);
          end
          fprintf('Trial %4d: %5.1f s\n', app.trialCounter, toc);
          app.trialCounter = app.trialCounter + 1;
          tic;
      end
    end

    function stopTask(app)
      stop(timerfind);                                                        % stop/delete timers; pause data stream
      delete(timerfind);
      app.dataTimer = 0;
      app.taskTimer = 0;
      app.lbj.verbose = 0;
      stopStream(app.lbj);
      app.lbj.verbose = 1;
      app.startButton.BackgroundColor = 'green';
      app.startButton.Text = 'Start';
      MetricsControlState(app, 'on', {app.startButton})
      [app.saccStartIndex, app.saccEndIndex] = processSignals(app.saccades, app);
      plotPosVel(app.posVelPlots, app, app.saccStartIndex, app.saccEndIndex, true);
      app.ampDur.lastN = 0;                                                   % force ampDur to plot
      plotAmpDur(app.ampDur, app);
%       doFits(app.fits, app);
%       drawnow;
      centerStimulus(app.visStim);
      app.stopAtTrialEnd = false;                                             % clear any request to stop
    end

    function taskError(~, ~, ~, ~)
      fprintf('timer error\n')
    end
  end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function openMetrics(app, varargin)
      fprintf(1,'Saccade Metrics v2.4\n %s\n', string(datetime));
      set(app.figure1, 'visible', 'off');
      tic;
      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        app.testMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
        app.doStimDisplay = true;
      else
        app.testMode = false;
        app.doStimDisplay = true;
      end
      if app.testMode || ~app.doStimDisplay
        app.warnText.Text = 'Test Mode';
      end

      % newAxes = axes(app.Panel);
      % plot(newAxes, [0, 1, 2, 3, 4], [2, 1, 2, 3, 4], '-r');
      
      delete(app.posAxes);
      app.posAxes = uiaxes(app.figure1, 'Position', [42, 594, 325, 200]);
      delete(app.velAxes);
      app.velAxes = uiaxes(app.figure1, 'Position', [42, 368, 325, 200]);
      delete(app.avgPosAxes);
      app.avgPosAxes = uiaxes(app.figure1, 'Position', [442, 594, 325, 200]);
      delete(app.avgVelAxes);
      app.avgVelAxes = uiaxes(app.figure1, 'Position', [445, 368, 325, 200]);
      delete(app.ampDurAxes);
      app.ampDurAxes = uiaxes(app.figure1, 'Position', [445, 132, 325, 200]);

      % disableDefaultInteractivity(app.posAxes); % these don't work
      % app.posAxes.Toolbar.Visible = 'off'; 
      % app.posAxes.Interactions = [];
      app.lbj = setupLabJack(app);
      if isempty(app.lbj)
        close all;
        return;
      end
      if (app.testMode)
        analogOut(app.lbj, 0, 2.5);                               % For debugging (AOuts to AIns)
      end
      app.saccades = MetricsSaccades(app);
      app.fits = MetricsFits(app);
      app.numChannels = app.lbj.numChannels;
      app.numOffsets = length(app.offsetsDeg);
      app.saccadeSamples = floor(app.saccadeTraceS * app.lbj.SampleRateHz);
      trialDurS = max(1.0, 2 * app.saccadeTraceS);
      app.trialSamples = floor(trialDurS * app.lbj.SampleRateHz);
      app.prestimDurS = min(trialDurS / 4, 0.250);
      app.posVelPlots = MetricsPosVelPlots(app);
      clearBuffers(app);
      app.visStim = MetricsStimulus(app);
      app.ampDur = MetricsAmpDur(app);
      set(app.figure1, 'CloseRequestFcn', {@app.closeMetrics});   % close function will close LabJack
      movegui(app.figure1, 'northeast');
      set(app.figure1, 'visible', 'on');
        end

        % Button pushed function: clearButton
        function clearButton_Callback(app, event)
      selection = uiconfirm(app.figure1, 'Really clear all data? (This cannot be undone).', 'Clear Data');
      switch selection
        case 'OK'
          clearAll(app.saccades);
          clearAll(app.ampDur, app);
          clearAll(app.fits, app);
          clearBuffers(app);
          plotPosVel(app.posVelPlots, app, 0, 0, true);
          app.calibrationText.Text = '';
      end
        end

        % Button pushed function: loadDataButton
        function loadDataButton_Callback(app, event)
      MetricsControlState(app, 'off', {})
      app.figure1.Visible = 'off';     % make sure uiputfile window is hidden for dialog
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', [app.folderPath, 'Data']);
      app.figure1.Visible = 'on';
      if fileName ~= 0
        oldTestMode = app.testMode;                               % keep testMode across data loads
        load([filePath fileName], 'a', 's', 'appData');
        app.testMode = oldTestMode;                               % keep testMode across data loads
        app.ampDur = a;
        app.saccades = s;
        clearBuffers(app);
        app.blocksDone = appData.blocksDone;
        app.calTrialsDone = appData.calTrialsDone;
        app.numSummed = appData.numSummed;
        app.offsetsDone = appData.offsetsDone;
        app.rawData = appData.rawData;
        app.posTrace = appData.posTrace;
        app.posSummed = appData.posSummed;
        app.posAvg = appData.posAvg;
        app.saccStartIndex = appData.saccStartIndex;
        app.saccEndIndex = appData.saccEndIndex;
        app.velTrace = appData.velTrace;
        app.velSummed = appData.velSummed;
        app.velAvg = appData.velAvg;
        app.viewDistanceText.Value = sprintf('%d', appData.viewDistance);
        app.thresholdDegText.Value = sprintf('%d', appData.thresholdDeg);
        app.thresholdDPSText.Value = sprintf('%d', appData.thresholdDPS);
        app.stopAfterText.Value = sprintf('%d', appData.stopAfter);
        app.PositionButton.Value = appData.positionButtonValue;
        app.SpeedButton.Value = appData.speedButtonValue;
        app.calibrationText.Text = sprintf('Calibration %.1f deg/V', app.saccades.degPerV);
        % [app.saccStartIndex, app.saccEndIndex] = processSignals(app.saccades, app);
        plotPosVel(app.posVelPlots, app, app.saccStartIndex, app.saccEndIndex, true);
        app.ampDur.lastN = 0;                                     % force ampDur to plot
        plotAmpDur(app.ampDur, app);
        doFits(app.fits, app);
      end
      MetricsControlState(app, 'on', {});
        end

        % Button pushed function: saveDataButton
        function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      MetricsControlState(app, 'off', {})
      fileName = doSaveData(app);
      MetricsControlState(app, 'on', {})
      selection = uiconfirm(app.figure1, sprintf('Data saved successfully to %s', fileName), 'Save Data', 'Icon', 'success');
        end

        % Button pushed function: savePlotsButton
        function savePlotsButton_Callback(app, event)
      MetricsControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', ['Saving to ', app.folderPath], ...
        'indeterminate','on');
      timeString = savePlot(app.posAxes, [app.folderPath, 'Pos/'], 'MT', 'png'); % same time for all file names
      savePlot(app.velAxes, [app.folderPath, 'Vel/'], 'MT', 'png', timeString);
      savePlot(app.avgPosAxes, [app.folderPath, 'AvgPos/'], 'MT', 'png', timeString);
      savePlot(app.avgVelAxes, [app.folderPath, 'AvgVel/'], 'MT', 'png', timeString);
      savePlot(app.ampDurAxes, [app.folderPath, 'AmpDur/'], 'MT', 'png', timeString);
      writeFitData(app.fits, app, timeString);
      writeAmpDurData(app.ampDur, app, timeString);
      close(progress);
      MetricsControlState(app, 'on', {})
        end

        % Button pushed function: startButton
        function startButton_Callback(app, event)
      if strcmp(app.startButton.Text, 'Start') % if start button, do the following
        if atTrialLimit(app)
          return
        end
        viewDistanceCM = str2double(app.viewDistanceText.Value);
        limitDistanceCM = maxViewDistanceCM(app.visStim, max(app.offsetsDeg));
        if viewDistanceCM > limitDistanceCM
          errordlg(sprintf('Viewing distance must be <= %d cm', floor(limitDistanceCM)));
          return;
        end
        setViewDistanceCM(app.visStim, app);
        app.saccades.thresholdDeg = str2double(app.thresholdDegText.Value);
        app.saccades.thresholdDPS = str2double(app.thresholdDPSText.Value);
        % create timer to control the task
        taskRateHz = 25;
        app.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate', ...
          'Period', 1.0/taskRateHz, 'ErrorFcn', {@app.taskError}, 'TimerFcn', {@app.taskController});
        if ~isvalid(app.taskTimer)
          fprintf('Metrics:startButton_Callback: Failed to obtain valid handle for task timer\n');
          return;
        end

        % create timer to get data from LabJack
        app.taskState = MetricsTaskState.taskStarttrial;
        app.trialStartTime = 0;
        app.dataState = MetricsDataState.dataIdle;
        dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
        app.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate', ...
          'Period', 1.0 / dataCollectRateHz, 'ErrorFcn', {@app.taskError},...
          'TimerFcn', {@app.collectData}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
        if ~isvalid(app.dataTimer)
          fprintf('Metrics:startButton_Callback: Failed to obtain valid handle for data timer\n');
          return;
        end
        % set the gui button to "running" state
        app.startButton.BackgroundColor = 'red';
        app.startButton.Text = 'Stop';
        MetricsControlState(app, 'off', {app.startButton})

        %% Start plots, data pickup, and data acquisition
        start(app.dataTimer);
        start(app.taskTimer);
        % Stop at trace end -- we're already running, so it's a the stop button
      elseif strcmp(app.startButton.Text, 'Stop')               % flag a stop at end of trace
        app.stopAtTrialEnd = true;
        app.startButton.Text = 'Stopping';
        app.startButton.BackgroundColor = 'yellow';
        %         drawnow;
        %% Stop -- we're already running, so it's a the stop button
      else % stop
        stopTask(app);
      end
        end

        % Value changed function: viewDistanceText
        function viewDistanceTextValueChanged(app, event)
      setViewDistanceCM(app.visStim, app);
        end

        % Value changed function: stopAfterText
        function stopAfterTextValueChanged(app, event)
      value = atTrialLimit(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'NSCI 20100';
            app.figure1.Tag = 'figure1';
            app.posAxes = uiaxes(app.figure1);
            app.posAxes.Tag = 'axes1';
            app.avgPosAxes = uiaxes(app.figure1);
            app.avgPosAxes.Tag = 'axes2';
            app.velAxes = uiaxes(app.figure1);
            app.velAxes.Tag = 'velAxes';
            app.avgVelAxes = uiaxes(app.figure1);
            app.avgVelAxes.Tag = 'axes4';
            app.ampDurAxes = uiaxes(app.figure1);
            app.ampDurAxes.Tag = 'axes5';
            app.startButton = uibutton(app.figure1, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
            app.startButton.Tag = 'startButton';
            app.startButton.Text = 'Start';
            app.clearButton = uibutton(app.figure1, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
            app.clearButton.Tag = 'clearButton';
            app.clearButton.Text = 'Clear Data';
            app.viewDistanceText = uieditfield(app.figure1, 'text');
            app.viewDistanceText.ValueChangedFcn = createCallbackFcn(app, @viewDistanceTextValueChanged, true);
            app.viewDistanceText.Tag = 'viewDistanceText';
            app.viewDistanceText.Value = '50';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Viewing Distance (cm):';
            app.thresholdDPSText = uieditfield(app.figure1, 'text');
            app.thresholdDPSText.Tag = 'thresholdDegText';
            app.thresholdDPSText.Value = '150';
            app.thresholdDegText = uieditfield(app.figure1, 'text');
            app.thresholdDegText.Tag = 'thresholdDegText';
            app.thresholdDegText.Value = '3';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Threshold Position (deg):';
            app.savePlotsButton = uibutton(app.figure1, 'push');
            app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
            app.savePlotsButton.Tag = 'savePlotsButton';
            app.savePlotsButton.Text = 'Save Plots';
            app.titleText = uilabel(app.figure1);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = 'NSCI 20100 Saccade Metrics Laboratory';
            app.saveDataButton = uibutton(app.figure1, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
            app.saveDataButton.Tag = 'saveDataButton';
            app.saveDataButton.Text = 'Save Data';
            app.loadDataButton = uibutton(app.figure1, 'push');
            app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
            app.loadDataButton.Tag = 'loadDataButton';
            app.loadDataButton.Text = 'Load Data';
            app.warnText = uilabel(app.figure1);
            app.warnText.Tag = 'warnText';
            app.warnText.Text = '';
            app.calibrationText = uilabel(app.figure1);
            app.calibrationText.Tag = 'calibrationText';
            app.calibrationText.Text = '';
            app.Filter60Hz = uicheckbox(app.figure1);
            app.Filter60Hz.Tag = 'filter60Hz';
            app.Filter60Hz.Text = 'Filter 60 Hz';
            app.Filter60Hz.Value = true;
            app.stopAfterText = uieditfield(app.figure1, 'text');
            app.stopAfterText.ValueChangedFcn = createCallbackFcn(app, @stopAfterTextValueChanged, true);
            app.stopAfterText.Tag = 'stopAfterText';
            app.stopAfterText.Value = '10';
            app.text2_2 = uilabel(app.figure1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = 'Stop when n >=';
            app.limitText = uilabel(app.figure1);
            app.limitText.Tag = 'limitText';
            app.limitText.Text = '';
            app.text3_2 = uilabel(app.figure1);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Threshold Speed (deg/s):';
            app.ThresholdType = uibuttongroup(app.figure1);
            app.ThresholdType.Title = 'Threshold Type';
            app.PositionButton = uiradiobutton(app.ThresholdType);
            app.PositionButton.Text = 'Position';
            app.PositionButton.Value = true;
            app.SpeedButton = uiradiobutton(app.ThresholdType);
            app.SpeedButton.Text = 'Speed';
            app.fitTable = uitable(app.figure1);
            app.fitTable.ColumnName = {'Speed/Accel.'; 'Intercept'; 'r^2'; 'SS'};
            app.fitTable.RowName = {'''Speed''; ''Accel.'''};
            app.statsTable = uitable(app.figure1);
            app.statsTable.ColumnName = {'F'; 'p'};
            app.statsTable.RowName = {};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metrics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)openMetrics(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end