classdef SaccadeMetrics < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1           matlab.ui.Figure
    axes1             matlab.ui.control.UIAxes
    axes2             matlab.ui.control.UIAxes
    axes3             matlab.ui.control.UIAxes
    axes4             matlab.ui.control.UIAxes
    axes5             matlab.ui.control.UIAxes
    startButton       matlab.ui.control.Button
    clearButton       matlab.ui.control.Button
    viewDistanceText  matlab.ui.control.EditField
    text2             matlab.ui.control.Label
    thresholdDegText  matlab.ui.control.EditField
    text3             matlab.ui.control.Label
    savePlotsButton   matlab.ui.control.Button
    titleText         matlab.ui.control.Label
    saveDataButton    matlab.ui.control.Button
    loadDataButton    matlab.ui.control.Button
    warnText          matlab.ui.control.Label
    calibrationText   matlab.ui.control.Label
    Filter60Hz        matlab.ui.control.CheckBox
  end

    
methods (Access = private)
  
  function closeEOG(app, ~, ~, handles)
    % this function is called  when the user closes the main window
    % close the timer and clear the LabJack handle
    %
    % fist check whether the task is running
    if strcmp(get(handles.startButton, 'String'), 'Stop')
      return;
    end
    selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
    switch selection
      case 'OK'
        try cleanup(handles.visStim); catch, end
        try delete(handles.visStim); catch, end
        try delete(handles.ampDur); catch, end
        try stop(timerfind); catch, end
        try delete(timerfind); catch, end
        try clear('handles.lbj'); catch, end
        delete(app.figure1);                                % close the program window
      case 'Cancel'
        return
    end
  end
               
  function collectData(app, obj, event)
    % reads stream data from the LabJack
    handles = obj.UserData;
    data = handles.data;
    lbj = handles.lbj;                                                     % obj.UserData is pointer to handles
    switch data.dataState
      case DataState.dataIdle
        return;
      case DataState.dataCollect
        [dRaw, ~] = getStreamData(lbj);                                     %#ok<*NASGU> % get stream data
        numNew = min(length(dRaw), data.trialSamples - data.samplesRead);
        data.rawData(data.samplesRead + 1:data.samplesRead + numNew, :) = dRaw(1:numNew, :);
        data.samplesRead = data.samplesRead + numNew;
        if (data.samplesRead == data.trialSamples)
          tStart = tic;
          handles.lbj.verbose = 0;
          stopStream(handles.lbj);
          handles.lbj.verbose = 1;
          data.dataState = DataState.dataIdle;
          data.taskState = TaskState.taskEndtrial;
        end
    end
  end
  
        function lbj = setupLabJack(app)
            %  get hardware info and do not continue if daq device/drivers unavailable
            
                lbj = labJackU6;                        % create the daq object
                open(lbj);                              % open connection to the daq
                if isempty(lbj.handle)
                    originalSize = get(0, 'DefaultUIControlFontSize');
                    set(0, 'DefaultUIControlFontSize', 14);
                    questdlg('Exit and check USB connections.', ...
                        'No LabJack Device Found', 'OK', 'OK');
                    set(0, 'DefaultUIControlFontSize', originalSize);
                else
                    fprintf(1,'EOG: LabJack Ready.\n\n');
                end
                % create input channel list
                removeChannel(lbj, -1);                     % remove all input channels
                addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
                lbj.SampleRateHz = 1000;                    % sample rate (Hz)
                lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
            
                % configure LabJack for analog input streaming
            
                errorCode = streamConfigure(lbj);
                if errorCode > 0
                    fprintf(1,'EOG: Unable to configure LabJack. Error %d.\n',errorCode);
                    return
                end
        end
        
        function taskController(app, obj, events, daqaxes)
                handles = obj.UserData;
                data = handles.data;
                lbj = handles.lbj;                                              	% get handle to LabJack
                visStim = handles.visStim;
                saccades = handles.saccades;
                ampDur = handles.ampDur;
            %     rtDists = handles.rtDists;
                switch data.taskState
                    case TaskState.taskStarttrial
                        if sum(data.offsetsDone) >= data.numOffsets                 % finished another block
                            data.offsetsDone = zeros(1, data.numOffsets);           % clear counters
                            data.blocksDone = data.blocksDone + 1;                  % increment block counter
                        end
                        data.offsetIndex = ceil(rand() * data.numOffsets);          % randomly selected saccade step
                        startIndex = data.offsetIndex;                              % mark the index where search starts
                        data.centeringTrial = false;
                        while true                                                  % find an unused, doable step
                            if data.offsetsDone(data.offsetIndex) == 0 && ...       % unused and doable
                                    ~stepOutOfRange(visStim, data.offsetsDeg(data.offsetIndex))
                                break;
                            end
                            data.offsetIndex = mod(data.offsetIndex, data.numOffsets) + 1; % try the next one
                            if data.offsetIndex == startIndex                       % no steps doable from current position
                                data.centeringTrial = true;                         % center the stimulus and try again
                                fprintf('centering the stimulus: at %.0f step asked %.0f max %.0f\n',...
                                    currentOffsetDeg(visStim), data.offsetsDeg(data.offsetIndex), maxDeg(visStim));
                                break;
                            end
                        end
            %             fprintf('Currently at %.0f; step asked %.0f; max doable %.0f\n',...
            %                         currentOffsetDeg(visStim), data.offsetsDeg(data.offsetIndex), maxDeg(visStim));
                        data.absStepIndex = mod(data.offsetIndex - 1, data.numOffsets / 2) + 1;
                        data.stepSign = sign(data.offsetsDeg(data.offsetIndex));
            %             if data.centeringTrial
            %                 fprintf('centering the stimulus');
            %             else
            %                 fprintf('doing index %d,  %.0f\n', data.offsetIndex, data.offsetsDeg(data.offsetIndex));
            %             end
                        if data.testMode                                         	% debugging- connect DACO0 to AIN0
                          	data.voltage = min(5.0, visStim.currentOffsetPix / 500.0);
                            analogOut(lbj, 0, 2.5 + data.voltage);
            %                 fprintf(' A0: %.3f V\n', 2.5 + data.voltage);
                        end
                        data.trialStartTimeS = clock;
                        data.taskState = TaskState.taskSettle;                      % go to settle state
                    case TaskState.taskSettle
                        if etime(clock, data.trialStartTimeS) > 0.015               % data settled for one taskTimer cycle
                            data.trialStartTimeS = clock;                         	% reset the trial clock
                            data.stimTimeS = data.prestimDurS + rand() * 0.125;   	% jitter the stimon time a bit
                            data.samplesRead = 0;
                            handles.lbj.verbose = 0;
                            startStream(handles.lbj);
                            handles.lbj.verbose = 1;
                            data.dataState = DataState.dataCollect;
                            data.taskState = TaskState.taskPrestim;              	% go to prestim state
                        end
                    case TaskState.taskPrestim
                       if etime(clock, data.trialStartTimeS) > data.stimTimeS
                            if data.centeringTrial
                               centerStimulus(visStim);
                            else
                                stepStimulus(visStim, data.offsetsDeg(data.offsetIndex));
                            end
                            data.taskState = TaskState.taskPoststim;
                        end
                    case TaskState.taskPoststim
                    case TaskState.taskEndtrial
                        if ~data.centeringTrial
                            [startIndex, endIndex] = processSignals(saccades, data);
                            plot(handles.posVelPlots, handles, startIndex, endIndex, false);
                            set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', saccades.degPerV));
                            addAmpDur(ampDur, data.offsetIndex, startIndex, endIndex);
                            plotAmpDur(ampDur);
                        end
            %             if startIndex > 0
            %                 addRT(rtDists{data.offsetIndex}, (startIndex / data.sampleRateHz  - data.stimTimeS) * 1000.0);
            %             end
            %             if (mod(sum(data.numSummed), data.numOffsets) == 0)
            %                 needsRescale = plot(rtDists{data.offsetIndex});
            %                 if needsRescale > 0
            %                      for i = 1:data.numOffsets
            %                          rescale(rtDists{i}, needsRescale);
            %                          plot(rtDists{i});
            %                     end
            %                 end
            %             end
                        data.trialStartTimeS = 0;
                        data.taskState = TaskState.taskStarttrial;
                end
        end
        
        function taskError(app, obj, events, handles)
                fprintf('timer error\n')
        end
        
    end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openEOG(app, varargin)
        % Create GUIDE-style callback args - Added by Migration Tool
        [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
        
        % This function has no output args, see OutputFcn.
        % hObject    handle to figure
        % eventdata  reserved - to be defined in a future version of MATLAB
        % handles    structure with handles and user data (see GUIDATA)
        % varargin   command line arguments to EOG (see VARARGIN)
        
        % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
        if ~isempty(varargin)
          testMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
          doStimDisplay = true;
        else
          testMode = true;
          doStimDisplay = true;
        end
        if testMode || ~doStimDisplay
          set(handles.warnText, 'string', 'Test Mode');
        end
        handles.output = hObject;                                               % select default command line output
        handles.saccades = EOGSaccades;
        set(hObject, 'CloseRequestFcn', {@app.closeEOG, handles});                  % close function will close LabJack
        handles.lbj = setupLabJack(app);
        handles.posVelPlots = EOGPosVelPlots(handles);
        handles.data = EOGTaskData(handles.lbj.numChannels, handles.lbj.SampleRateHz);
        handles.data.testMode = testMode;
        handles.data.doStimDisplay = doStimDisplay;
        handles.visStim = EOGStimulus(handles.data);         	% needs data set up first for doStimDisplay
        if (handles.data.testMode)
          analogOut(handles.lbj, 0, 2.5);                     % For debugging (AOuts to AIns)
        end
        handles.ampDur = EOGAmpDur(handles.axes5, handles.data.offsetsDeg, handles.lbj.SampleRateHz);
        movegui(hObject, 'northeast');
        guidata(hObject, handles);                                              % save the selection
    end

    % Value changed function: Filter60Hz
    function Filter60Hz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                set60HzFilter(handles.data, get(hObject,'Value'));
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                originalSize = get(0, 'DefaultUIControlFontSize');
                set(0, 'DefaultUIControlFontSize', 14);
                selection = questdlg('Really clear all data? (This cannot be undone).', 'Clear Data', 'Yes', 'No', 'Yes');
                set(0, 'DefaultUIControlFontSize', originalSize);
                switch selection
                    case 'Yes'
                    clearAll(handles.saccades);
                    clearAll(handles.ampDur);
            %         for i = 1:handles.data.numOffsets
            %             clearAll(handles.rtDists{i});
            %         end
                    clearAll(handles.data);
                    plot(handles.posVelPlots, handles, 0, 0, true);
                    set(handles.calibrationText, 'string', '');
                    guidata(hObject, handles);
                end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                EOGControlState(handles, 'off', {})
                [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop');
                if fileName ~= 0
                    testMode = handles.data.testMode;                               % keep testMode across data loads
                    load([filePath fileName]);
                    handles.data = d;
                    handles.data.testMode = testMode;                               % keep testMode across data loads
                    handles.ampDur = a;
                    handles.saccades = s;
                    set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', handles.saccades.degPerV));
            %         handles.rtDists{1} = r1;
            %         handles.rtDists{2} = r2;
            %         handles.rtDists{3} = r3;
            %         handles.rtDists{4} = r4;
            
                    % saved axes handles generally aren't valid if we are in a new run.
                    % Load the handles with the current axes handles.
            
                    handles.ampDur.fHandle = handles.axes5;                         % loaded axes handle might be invalid
            %         handles.rtDists{1}.fHandle = handles.axes6;
            %         handles.rtDists{2}.fHandle = handles.axes7;
            %         handles.rtDists{3}.fHandle = handles.axes8;
            %         handles.rtDists{4}.fHandle = handles.axes9;
                    guidata(hObject, handles);                                      % save the selections
            
                    [startIndex, endIndex] = processSignals(handles.saccades, d);
                    plot(handles.posVelPlots, handles, startIndex, endIndex, true);
                    handles.ampDur.lastN = 0;                                       % force ampDur to plot
                    plotAmpDur(handles.ampDur);
            %         for i = 1:4
            %             plot(handles.rtDists{i});
            %         end
            
                end
                EOGControlState(handles, 'on', {})
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Saving the workspace for a GUI isn't simple.  What we have accessible in this
            % environment is mostly the handles.  If we save, it's an attempt to save
            % handles, which doesn't work.  Instead, we get a list of all the properties
            % from the EOGTaskData class, and then use eval statement to assign those to
            % local variable and save them (one by one).
                EOGControlState(handles, 'off', {})
                [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/EOGData.mat');
                if fileName ~= 0
                    d = handles.data;
                    a = handles.ampDur;
                    s = handles.saccades;
            %         r1 = handles.rtDists{1};
            %         r2 = handles.rtDists{2};
            %         r3 = handles.rtDists{3};
            %         r4 = handles.rtDists{4};
                   save([filePath fileName], 'd', 'a', 's');
                end
                EOGControlState(handles, 'on', {})
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                EOGControlState(handles, 'off', {})
                [fileName, filePath] = uiputfile('*.pdf', 'Save Window Plots as PDF', '~/Desktop/EOGPlots.pdf');
                if fileName ~= 0
                    set(handles.figure1, 'PaperUnits', 'inches');
                    figurePos = get(handles.figure1, 'position');
                    widthInch = figurePos(3) / 72;
                    heightInch = figurePos(4) / 72;
                    set(handles.figure1, 'inverthardcopy', 'off');                  % keep gray background
                    set(handles.figure1, 'PaperOrientation', 'landscape');
                    set(handles.figure1, 'PaperSize', [widthInch + 1.0, heightInch + 1.0]);
                    set(handles.figure1, 'PaperPosition', [0.5, 0.5, widthInch, heightInch]);
                    print(handles.figure1, '-dpdf', '-r600', [filePath fileName]);
                end
                EOGControlState(handles, 'on', {})
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                if strcmp(get(handles.startButton, 'String'), 'Start') % if start button, do the following
                    fprintf(1,'\Saccade Metrics v2.0\n %s\n', datestr(clock));
                    viewDistanceCM = str2double(get(handles.viewDistanceText, 'string'));
                    limitDistanceCM = maxViewDistanceCM(handles.visStim, max(handles.data.offsetsDeg));
                    if viewDistanceCM > limitDistanceCM
                        errordlg(sprintf('Viewing distance must be <= %d cm', floor(limitDistanceCM)));
                        return;
                    end
                    setViewDistanceCM(handles.visStim, str2double(get(handles.viewDistanceText, 'string')));
                    handles.saccades.thresholdDeg = str2double(get(handles.thresholdDegText, 'string'));
                    % create timer to control the task
                    taskRateHz = 25;
                    handles.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate',...
                        'Period', 1.0/taskRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles}, 'TimerFcn',...
                        {@app.taskController, [handles.axes1 handles.axes2 handles.axes3 handles.axes4]});
            
                    % create timer to get data from LabJack
                    handles.data.taskState = TaskState.taskStarttrial;
                    handles.data.trialStartTimeS = 0;
                    handles.data.dataState = DataState.dataIdle;
                    dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
                    handles.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate',...
                        'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles},...
                        'TimerFcn', {@app.collectData}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
            
                    % set the gui button to "running" state
                    set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
                    EOGControlState(handles, 'off', {handles.startButton})
                    % save timers to handles and update the GUI display
            
                    %% Start plots, data pickup, and data acquisition
                    start(handles.dataTimer);
                    start(handles.taskTimer);
            
                %% Stop -- we're already running, so it's a the stop button
                else % stop
                    stop(timerfind);                                                        % stop/delete timers; pause data stream
                    delete(timerfind);
                    handles.dataTimer = 0;
                    handles.taskTimer = 0;
                    stopStream(handles.lbj);
                    set(handles.startButton, 'string', 'Start','backgroundColor', 'green');
                    EOGControlState(handles, 'on', {handles.startButton})
                    drawnow;
                    centerStimulus(handles.visStim);                                        % recenter fixspot
                end
                guidata(hObject, handles);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.Tag = 'axes1';
      app.axes2 = uiaxes(app.figure1);
      app.axes2.Tag = 'axes2';
      app.axes3 = uiaxes(app.figure1);
      app.axes3.Tag = 'axes3';
      app.axes4 = uiaxes(app.figure1);
      app.axes4.Tag = 'axes4';
      app.axes5 = uiaxes(app.figure1);
      app.axes5.Tag = 'axes5';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.viewDistanceText = uieditfield(app.figure1, 'text');
      app.viewDistanceText.Tag = 'viewDistanceText';
      app.viewDistanceText.Value = '50';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Viewing Distance (cm):';
      app.thresholdDegText = uieditfield(app.figure1, 'text');
      app.thresholdDegText.Tag = 'thresholdDegText';
      app.thresholdDegText.Value = '3';
      app.text3 = uilabel(app.figure1);
      app.text3.Tag = 'text3';
      app.text3.Enable = 'off';
      app.text3.Text = 'Saccade Threshold (deg):';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Saccade Metrics Laboratory';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.calibrationText = uilabel(app.figure1);
      app.calibrationText.Tag = 'calibrationText';
      app.calibrationText.Text = '';
      app.Filter60Hz = uicheckbox(app.figure1);
      app.Filter60Hz.ValueChangedFcn = createCallbackFcn(app, @Filter60Hz_Callback, true);
      app.Filter60Hz.Tag = 'Filter60Hz';
      app.Filter60Hz.Text = 'Filter 60 Hz';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SaccadeMetrics(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openEOG(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end