classdef StretchReceptor_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    vContAxes            matlab.ui.control.UIAxes
    vTrigAxes            matlab.ui.control.UIAxes
    axes3                matlab.ui.control.UIAxes
    startButton          matlab.ui.control.Button
    clearButton          matlab.ui.control.Button
    savePlotsButton      matlab.ui.control.Button
    titleText            matlab.ui.control.Label
    warnText             matlab.ui.control.Label
    contMSPerDivButton   matlab.ui.control.DropDown
    text9                matlab.ui.control.Label
    thresholdSlider      matlab.ui.control.Slider
    vPerDivButton        matlab.ui.control.DropDown
    text10               matlab.ui.control.Label
    singleSpikeCheckbox  matlab.ui.control.CheckBox
    retriggerButton      matlab.ui.control.Button
    maxISIMenu           matlab.ui.control.DropDown
    text11               matlab.ui.control.Label
    filterMenu           matlab.ui.control.DropDown
    text12               matlab.ui.control.Label
    volumeSlider         matlab.ui.control.Slider
    text13               matlab.ui.control.Label
  end

  
  properties (Access = public)
    contMSPerDiv;             % ms per division for the continuous plot
    contPlotRescale = false;  % flag to rescale the continuous plot
    contSamples;              % number of samples in the continuous plot
    contTimeDivs = 20;        % number of time divisions in the continuous plot
    filter;                   % low pass filter that is currently in use
    filters;                  % the set of possible low pass filters
    filteredTrace;            % filtered version of samples
    inSpike = false;          % flag showing that spike finding routine is in middle of a spike
    lastSpikeIndex;           % index of last triggered spike
    lbj;                      % handle for LabJack object
    maxContSamples;           % the maximum possible number of samples for continue trace
    rawData;                  % most recent raw snippet of sampled data
    rawTrace;                 % raw version of samples
    samplesRead = 0;          % number of samples read in the continuous trace
    spikeIndices;             % indices for unplotted spikes in continuous trace
    stopAtTraceEnd = false;   % flag for displaying a single continous trace
    thresholdV = 1.0;         % used by signals and plots  
    testMode = false;         % flag for testing mode
    vDivs = 6;                % number of voltage (y) divisions
    vPerDiv = 1.0;            % volts per division
  end
    
  methods (Access = private)
    
    function clearPlots(~, handles)
      app.spikeIndices = [];
      app.inSpike = false;
      app.samplesRead = 0;
      app.lastSpikeIndex = 2 * app.maxContSamples;                          % flag start with invalid index
      clearAll(handles.plots, app, handles);
      clearAll(handles.isiPlot);
    end
    
    function closeSR(app, ~, ~, handles)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle

      % fist check whether the task is running
      if strcmp(get(handles.startButton, 'String'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try delete(handles.ampDur); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                              % close the program window
        case 'Cancel'
          return
      end
    end
    
    function collectData(app, obj, ~)
      % reads and processes stream data from the LabJack
      handles = obj.UserData;                                               % obj.UserData is pointer to handles
      % If the continuous display scaling has just changed, we will refresh that plot
      if app.contPlotRescale
        setLimits(app, handles);
        clearContPlot(handles.plots, app, handles)
        clearTriggerPlot(handles.plots, app, handles)
        app.contPlotRescale = false;
      end
      % We need a new trace if we hit or exceeded the sample buffer limit on the previous call
      if (app.samplesRead >= app.contSamples)
        app.samplesRead = 0;
        app.lastSpikeIndex = app.lastSpikeIndex - app.contSamples;
        if app.stopAtTraceEnd                                         % if stopAtTraceEnd, stop data collection
          handles.plots.samplesPlotted = 0;
          doStartButton(app, handles);
          %                     startButton_Callback(handles.startButton, event, handles);
        end
      end
      if (handles.plots.samplesPlotted > app.samplesRead)
        if handles.plots.singleSpike
          clearContPlot(handles.plots, app, handles);
        else
          clearAll(handles.plots, app, handles);
        end
      end
      
      % read and process any new data
      
      [dRaw, ~] = getStreamData(app.lbj);                         % get stream data
      
      % the LabJack marks lost data as -9999.  We clip it out and don't do anything more. Typically lost data appear
      % at the end of the trace (after the buffer has filled).  Oddly, there is generally one spurious large value
      % (+10.115) that appears exactly 8 samples before the first error values.  We clip that out as well.
      
      dRaw(dRaw > 10.0) = [];                                         % and clip out all error values
      dRaw(dRaw == -9999) = [];                                         % and clip out all error values
      %     firstErrorIndex = find(dRaw == -9999, 1, 'first');              % get the first error index
      %     if ~isempty(firstErrorIndex)                                    % for one or more errors,
      %         fprintf('found an error\n');
      %         for i = firstErrorIndex - 10:firstErrorIndex + 10
      %             fprintf('%.3f ', dRaw(i));
      %         end
      %         fprintf('\n');
      %         if (firstErrorIndex > 8)                                    % clip out the spurious erroneous value
      %             dRaw(firstErrorIndex - 8) = [];
      %         end
      %         dRaw(dRaw == -9999) = [];                                   % and clip out all error values
      %     end
      
      % now we can process any valid data
      
      numNew = min(length(dRaw), app.contSamples - app.samplesRead);
      if numNew > 0
        app.rawData(app.samplesRead + 1:app.samplesRead + numNew) = dRaw(1:numNew);
        processSignals(handles.signals, app, app.samplesRead, numNew);
        app.samplesRead = app.samplesRead + numNew;
        doPlot(handles.plots, app, handles);
        if numNew < length(dRaw)                                    % more data read from LabJack?
          if app.stopAtTraceEnd                                     % if stopAtTraceEnd, stop data collection
            handles.plots.samplesPlotted = 0;
            app.samplesRead = 0;
            doStartButton(app, handles);
%             startButton_Callback(handles.startButton, event, handles);
          else
            numNew = length(dRaw) - numNew;
            app.rawData(1:numNew) = dRaw(end - numNew + 1:end);
            processSignals(handles.signals, app, 0, numNew);
            app.samplesRead = numNew;
          end
        end
      end
      guidata(handles.startButton, handles);                          % save variables (guidata requires gui decendent
    end
    
    function collectError(~, ~, ~, ~)
      fprintf('data error');
      dbstack
    end
    
    function doStart(app, handles)
      dataCollectRateHz = 25;                                 % prevent overflow w/o blocking other activity
      handles.dataTimer = timer('name', 'CollectData', 'ExecutionMode', 'fixedRate', 'busyMode', 'drop',...
        'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.collectError, handles},...
        'TimerFcn', {@app.collectData}, 'StartDelay', 0.050);   % startDelay allows rest of the gui to execute
      
      % create timer to make fake spikes for LabJack
      if app.testMode
        fakeSpikeRateHz = 4;
        handles.fakeSpikeTimer = timer('Name', 'FakeSpikes', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0 / fakeSpikeRateHz, 'UserData', handles, 'ErrorFcn', {@app.fakeSpikeError, handles},...
          'TimerFcn', {@app.fakeSpike}, 'StartDelay', 0.050);
      end
      
      % set the gui button to "running" state
      % clear the plots
      if handles.plots.singleSpike
        clearContPlot(handles.plots, app, handles);
      else
        clearAll(handles.plots, app, handles);
        app.lastSpikeIndex = 2 * app.maxContSamples;      % flag start of ISI sequence
      end
      
      %     set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
      %     SRControlState(handles, 'off', {handles.startButton})
      startStream(app.lbj);
      
      % Start plots, data pickup, and data acquisition
      start(handles.dataTimer);
      if app.testMode
        start(handles.fakeSpikeTimer);
      end
    end
    
    %% doStartButton -- do the functions associated with a press of the start button
    function doStartButton(app, handles)     
      % Start request 
      if strcmp(get(handles.startButton, 'String'), 'Start')      % if start button, do the following
      fprintf(1,'\nStretchReceptor v2.0\n %s\n', datestr(clock));
      app.stopAtTraceEnd = false;                        % clear any requests to stop at end of next trace
      set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
      SRControlState(handles, 'off', {handles.startButton, handles.clearButton})
      doStart(app, handles);
      % Stop request -- we're already running, so it's a the stop button
      elseif strcmp(get(handles.startButton, 'String'), 'Stop')   % if start button, do the following
        app.stopAtTraceEnd = true;                       % flag a stop at the end of this trace
        set(handles.startButton, 'string', 'Stopping','backgroundColor', 'yellow');
        SRControlState(handles, 'on', {handles.startButton, handles.clearButton})
        drawnow;
      % Stop immediately -- already stopping, so we've been asked to stop immediately
      else 
        doStop(app, handles);
        set(handles.startButton, 'string', 'Start','backgroundColor', 'green');
        SRControlState(handles, 'on', {handles.startButton, handles.clearButton})
        drawnow;
      end
      guidata(handles.startButton, handles);                  % save variables
    end
    
    %% doStop -- stop data collection
    function doStop(app, handles)
      stop(timerfind);                                        % stop/delete timers; pause data stream
      delete(timerfind);
      handles.dataTimer = 0;
      handles.fakeSpikeTimer = 0;
      stopStream(app.lbj);
      clearAll(handles.signals, app);                              % stop audio processing
    end
    
    function fakeSpike(app, ~, ~)
      persistent count;
      
      if isempty(count)
        count = 1;
      else
        count = count + 1;
      end
%       numSpikes = 1 + (mod(count, 5) == 0);
      numSpikes = 1;
      for s = 1:numSpikes
        analogOut(app.lbj, 0, 4.5);
        java.lang.Thread.sleep(1.5);
        analogOut(app.lbj, 0, 1.5);
        java.lang.Thread.sleep(3.0);
        analogOut(app.lbj, 0, 2.5);
        java.lang.Thread.sleep(3.0);
      end
    end
    
    function fakeSpikeError(~, ~, ~, ~)
      fprintf('fake spike error\n');
    end
    
    function lbj = openLabJack(~)
      %  get hardware info and do not continue if daq device/drivers unavailable
      lbj = labJackU6;                        % create the daq object
      open(lbj);                              % open connection to the daq
      if isempty(lbj.handle)
        originalSize = get(0, 'DefaultUIControlFontSize');
        set(0, 'DefaultUIControlFontSize', 14);
        questdlg('Exit and check USB connections.', 'No LabJack Device Found', 'OK', 'OK');
        set(0, 'DefaultUIControlFontSize', originalSize);
      else
        fprintf(1,'StretchReceptor: LabJack Ready\n\n');
      end
      % create input channel list
      removeChannel(lbj, -1);                     % remove all input channels
      addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
      lbj.SampleRateHz = 5000;                    % sample rate (Hz)
      lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
      
      % configure LabJack for analog input streaming
      
      errorCode = streamConfigure(lbj);
      if errorCode > 0
        fprintf(1,'StretchReceptor: Unable to configure LabJack. Error %d.\n',errorCode);
        return
      end
    end
    
    %% setLimits -- used when plot scaling changes
    function setLimits(app, handles)
      app.samplesRead = 0;
      app.spikeIndices = [];
      app.contSamples = app.contMSPerDiv / 1000.0 * app.lbj.SampleRateHz * app.contTimeDivs;
      vLimit = app.vPerDiv * app.vDivs / 2.0;
      threshV = get(handles.thresholdSlider, 'value');
      threshV = max(-vLimit * 0.9, min(vLimit * 0.9, threshV));
      set(handles.thresholdSlider, 'value', threshV);
      app.thresholdV = threshV;
      set(handles.thresholdSlider, 'max', vLimit);
      set(handles.thresholdSlider, 'min', -vLimit);
    end
    
  end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openSR(app, varargin)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
      
      % This function has no output args, see OutputFcn.
      % hObject    handle to figure
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      % varargin   command line arguments to SR (see VARARGIN)
      set(app.figure1, 'visible', 'off');
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        tMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
      else
        tMode = true;
      end
      if tMode
        set(handles.warnText, 'string', 'Test Mode');
      end
      
      app.contMSPerDiv = str2double(app.contMSPerDivButton.Items{endsWith(app.contMSPerDivButton.Items, ...
        app.contMSPerDivButton.Value)});
      
      handles.output = hObject;                                      	% select default command line output
      set(hObject, 'CloseRequestFcn', {@app.closeSR, handles});       % close function will close LabJack
      app.lbj = openLabJack(app);                                    % LJ first, it has no contigencies
      maxMSPerDiv = str2double(app.contMSPerDivButton.Items{end});
      app.maxContSamples = app.contTimeDivs * maxMSPerDiv / 1000.0 * app.lbj.SampleRateHz;
      app.filteredTrace = zeros(app.maxContSamples, 1);               % filtered voltage trace
      app.rawData = zeros(app.maxContSamples, 1);                     % raw data
      app.rawTrace = zeros(app.maxContSamples, 1);                    % continuous voltage trace
      setLimits(app, handles);
      filterStrings = app.filterMenu.Items;
      app.filters = cell(length(filterStrings), 1);
      for f = 1:length(filterStrings)
        cutOffHz = str2double(filterStrings{f});
        theFilter = design(fdesign.highpass('Fst,Fp,Ast,Ap', 0.5 * cutOffHz / app.lbj.SampleRateHz, ...
          2 * cutOffHz / app.lbj.SampleRateHz, 60, 1), 'butter');
        theFilter.persistentmemory = true;                            % piecemeal filtering of trace
        theFilter.states = 1;                                         % uses scalar expansion.
        app.filters{f} = theFilter;
        if strcmp(filterStrings{f}, app.filterMenu.Value)
          app.filter = theFilter;                                     % set the active filter
        end
      end
      handles.isiPlot = SRISIPlot(handles);                           % isi plot before signal processing
      handles.signals = SRSignalProcess(handles, app);                % signal processing after data
      handles.plots = SRPlots(handles, app);
      handles.signals.fH = handles;
      % set up test mode
      app.testMode = tMode;
      if app.testMode
        analogOut(app.lbj, 0, 2.5);                             % For debugging (AOuts to AIns)
      end
      movegui(hObject, 'northeast');
      set(app.figure1, 'visible', 'on');
      guidata(hObject, handles);                                                   % save the selection
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      if strcmp(get(handles.startButton, 'String'), 'Start')          % currently paused
        clearPlots(app, handles);                                        % clear immediately
      else
        doStop(app, handles);                                            % stop data collection
        clearPlots(app, handles);                                        % clear the plots and data
        doStart(app, handles);                                           % re-start data collection
      end
      guidata(hObject, handles);
    end

    % Value changed function: contMSPerDivButton
    function contMSPerDivButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
                contents = cellstr(get(hObject,'String'));
                newValue = str2double(contents{get(hObject, 'Value')});
                if newValue ~= app.contMSPerDiv
                    app.contMSPerDiv = newValue;
                    if strcmp(get(handles.startButton, 'String'), 'Start')  % if we're not running
                        setLimits(app, handles);
                        clearContPlot(handles.plots, app, handles)
                    else
                        app.contPlotRescale = true;                % else let dataCollect do the update
                    end
                end
    end

    % Value changed function: filterMenu
    function filterMenu_Callback(app, event)
      app.filter = app.filters{strcmp(app.filterMenu.Items, app.filterMenu.Value)};
    end

    % Value changed function: maxISIMenu
    function maxISIMenu_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      setISIMaxS(handles.isiPlot);
    end

    % Button pushed function: retriggerButton
    function retriggerButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                clearTriggerPlot(handles.plots, app, handles);
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      SRControlState(handles, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/SRData', ...
        'indeterminate','on');
      
      timeString = savePlot(app.vContAxes, '~/Desktop/SRData/FullTrace/', 'SR', 'png');
      savePlot(app.vTrigAxes, '~/Desktop/SRData/SpikeTrace/', 'SR', 'png', timeString);
      savePlot(app.axes3, '~/Desktop/SRData/ISI/', 'SR', 'png', timeString);       
      filePath = '~/Desktop/SRData/Matlab/';
      if ~isfolder(filePath)                      % folder exist?
        mkdir(filePath);
      end
      fileName = sprintf('SR%s.mat', timeString);
      isiMS = handles.isiPlot.isiMS(1:handles.isiPlot.isiNum);
      save([filePath fileName], 'isiMS');
      backupFile(filePath, '~/Desktop', '~/Documents/Respository');

      filePath = '~/Desktop/SRData/Excel/';
      if ~isfolder(filePath)                      % folder exist?
        mkdir(filePath);
      end
      fileName = sprintf('SR%s.xlsx', timeString);
      t = table(isiMS);
      writetable(t, [filePath fileName]);
      backupFile(filePath, '~/Desktop', '~/Documents/Respository');
      
      close(progress);
      SRControlState(handles, 'on', {})
    end

    % Value changed function: singleSpikeCheckbox
    function singleSpikeCheckbox_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
    
        handles.plots.singleSpike = get(hObject, 'value');
        if handles.plots.singleSpike
          set(handles.retriggerButton, 'Enable', 'on');
%                     retriggerButton_Callback(hObject, eventdata, handles);  % clear plot and enable for a single spike
          clearTriggerPlot(handles.plots, app, handles);
        else
            set(handles.retriggerButton, 'Enable', 'off');
        end
      guidata(hObject, handles);                                  % save change
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      doStartButton(app, handles)
    end

    % Value changed function: thresholdSlider
    function thresholdSlider_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      app.thresholdV = app.thresholdSlider.Value;
      clearTriggerPlot(handles.plots, handles);
      guidata(hObject, handles);                                  % save change
    end

    % Value changed function: vPerDivButton
    function vPerDivButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                contents = cellstr(get(hObject,'String'));
                newValue = str2double(contents{get(hObject, 'Value')});
                if newValue ~= app.vPerDiv
                    app.vPerDiv = newValue;
                    if strcmp(get(handles.startButton, 'String'), 'Start')  % if we're not running
                       setLimits(app, handles);
                       clearAll(handles.plots, app, handles);
                    else
                       app.contPlotRescale = true;                % else let dataCollect do the update
                    end
                end
            	guidata(hObject, handles);                                  % save change
    end

    % Value changed function: volumeSlider
    function volumeSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                setVolume(handles.signals);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Tag = 'figure1';
      app.vContAxes = uiaxes(app.figure1);
      app.vContAxes.Tag = 'axes1';
      app.vTrigAxes = uiaxes(app.figure1);
      app.vTrigAxes.Tag = 'axes2';
      app.axes3 = uiaxes(app.figure1);
      app.axes3.Tag = 'axes3';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Data';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Stretch Receptor Laboratory';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.contMSPerDivButton = uidropdown(app.figure1);
      app.contMSPerDivButton.Items = {'10', '25', '50', '100', '250', '500', '1000'};
      app.contMSPerDivButton.ValueChangedFcn = createCallbackFcn(app, @contMSPerDivButton_Callback, true);
      app.contMSPerDivButton.Tag = 'contMSPerDivButton';
      app.contMSPerDivButton.Value = '100';
      app.text9 = uilabel(app.figure1);
      app.text9.Tag = 'text9';
      app.text9.Text = ' Time per Division (ms):';
      app.thresholdSlider = uislider(app.figure1);
      app.thresholdSlider.Limits = [-1 1];
      app.thresholdSlider.MajorTicks = [];
      app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
      app.thresholdSlider.MinorTicks = [];
      app.thresholdSlider.Tag = 'thresholdSlider';
      app.thresholdSlider.Value = 1;
      app.vPerDivButton = uidropdown(app.figure1);
      app.vPerDivButton.Items = {'0.1', '0.2', '0.5', '1.0', '2.0', '5.0'};
      app.vPerDivButton.ValueChangedFcn = createCallbackFcn(app, @vPerDivButton_Callback, true);
      app.vPerDivButton.Tag = 'vPerDivButton';
      app.vPerDivButton.Value = '1.0';
      app.text10 = uilabel(app.figure1);
      app.text10.Tag = 'text10';
      app.text10.Text = ' Volts per Division:';
      app.singleSpikeCheckbox = uicheckbox(app.figure1);
      app.singleSpikeCheckbox.ValueChangedFcn = createCallbackFcn(app, @singleSpikeCheckbox_Callback, true);
      app.singleSpikeCheckbox.Tag = 'singleSpikeCheckbox';
      app.singleSpikeCheckbox.Text = 'Single Spike';
      app.retriggerButton = uibutton(app.figure1, 'push');
      app.retriggerButton.ButtonPushedFcn = createCallbackFcn(app, @retriggerButton_Callback, true);
      app.retriggerButton.Tag = 'retriggerButton';
      app.retriggerButton.Enable = 'off';
      app.retriggerButton.Text = 'Re-trigger';
      app.maxISIMenu = uidropdown(app.figure1);
      app.maxISIMenu.Items = {'0.10', '0.25', '0.50', '1.0', '2.5', '5.0'};
      app.maxISIMenu.ValueChangedFcn = createCallbackFcn(app, @maxISIMenu_Callback, true);
      app.maxISIMenu.Tag = 'maxISIMenu';
      app.maxISIMenu.Value = '0.50';
      app.text11 = uilabel(app.figure1);
      app.text11.Tag = 'text11';
      app.text11.Text = 'Maximum ISI (s):';
      app.filterMenu = uidropdown(app.figure1);
      app.filterMenu.Items = {'1', '100', '250'};
      app.filterMenu.ValueChangedFcn = createCallbackFcn(app, @filterMenu_Callback, true);
      app.filterMenu.Tag = 'filterMenu';
      app.filterMenu.Value = '1';
      app.text12 = uilabel(app.figure1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'High-pass Filter (Hz):';
      app.volumeSlider = uislider(app.figure1);
      app.volumeSlider.Limits = [2 6];
      app.volumeSlider.MajorTicks = [];
      app.volumeSlider.ValueChangedFcn = createCallbackFcn(app, @volumeSlider_Callback, true);
      app.volumeSlider.MinorTicks = [];
      app.volumeSlider.Tag = 'volumeSlider';
      app.volumeSlider.Value = 3;
      app.text13 = uilabel(app.figure1);
      app.text13.Tag = 'text13';
      app.text13.Text = 'Volume';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = StretchReceptor_App(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openSR(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end