classdef StretchReceptor < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    text2_2              matlab.ui.control.Label
    spikesPerISIText     matlab.ui.control.EditField
    limitText            matlab.ui.control.Label
    text2                matlab.ui.control.Label
    stopAfterText        matlab.ui.control.EditField
    text13               matlab.ui.control.Label
    volumeSlider         matlab.ui.control.Slider
    text12               matlab.ui.control.Label
    filterMenu           matlab.ui.control.DropDown
    text11               matlab.ui.control.Label
    maxISIMenu           matlab.ui.control.DropDown
    retriggerButton      matlab.ui.control.Button
    singleSpikeCheckbox  matlab.ui.control.CheckBox
    text10               matlab.ui.control.Label
    vPerDivButton        matlab.ui.control.DropDown
    thresholdSlider      matlab.ui.control.Slider
    text9                matlab.ui.control.Label
    contMSPerDivButton   matlab.ui.control.DropDown
    warnText             matlab.ui.control.Label
    titleText            matlab.ui.control.Label
    savePlotsButton      matlab.ui.control.Button
    clearButton          matlab.ui.control.Button
    startButton          matlab.ui.control.Button
    isiAxes              matlab.ui.control.UIAxes
    vTrigAxes            matlab.ui.control.UIAxes
    vContAxes            matlab.ui.control.UIAxes
  end

  
  properties (Access = public)
    contMSPerDiv;             % ms per division for the continuous plot
    contPlotRescale = false;  % flag to rescale the continuous plot
    contSamples;              % number of samples in the continuous plot
    contTimeDivs = 20;        % number of time divisions in the continuous plot
    dataTimer;                % timer for collecting data
    fakeSpikeTimer;           % timer for making fake spikes
    filter;                   % low pass filter that is currently in use
    filters;                  % the set of possible low pass filters
    filteredTrace;            % filtered version of samples
    inSpike = false;          % flag showing that spike finding routine is in middle of a spike
    isiPlot;                  % handle to SRISIPlot
    lastSpikeIndex;           % index of last triggered spike
    lbj;                      % handle for LabJack object
    maxContSamples;           % the maximum possible number of samples for continue trace
    rawData;                  % most recent raw snippet of sampled data
    rawTrace;                 % raw version of samples
    samplesRead = 0;          % number of samples read in the continuous trace
    signals;                  % handle to SRSignals object
    spikeIndices;             % indices for unplotted spikes in continuous trace
    spikesPerISI = 1;         % number of spikes pooled per ISI estimate
    stopAtTraceEnd = false;   % flag for displaying a single continous trace
    thresholdV = 1.0;         % used by signals and plots  
    testMode = false;         % flag for testing mode
    tracePlots;               % handle to SRPlots object
    vDivs = 6;                % number of voltage (y) divisions
    vPerDiv = 1.0;            % volts per division
  end
  methods (Access = public)

    function atLimit = atISILimit(app)
      atLimit = app.isiPlot.isiNum >= str2double(app.stopAfterText.Value);
      if atLimit
        app.limitText.Text = 'At requested ISI count';
      else
        app.limitText.Text = '';
      end
    end

    %% doStartButton -- do the functions associated with a press of the start button
    function doStartButton(app)
      % Start request
      if strcmp(app.startButton.Text, 'Start')              % if start button, do the following
        if atISILimit(app)
          return;
        end
        fprintf(1,'\nStretchReceptor v2.0\n %s\n', datetime('now', 'Format', 'MMM-dd-HHmmss'));
        app.stopAtTraceEnd = false;                         % clear any requests to stop at end of next trace
        app.startButton.BackgroundColor = 'red';
        app.startButton.Text = 'Stop';
        SRControlState(app, 'off', {app.startButton, app.clearButton})
        doStart(app);
        % Stop request -- we're already running, so it's a the stop button
      elseif strcmp(app.startButton.Text, 'Stop')           % if start button, do the following
        app.stopAtTraceEnd = true;                          % flag a stop at the end of this trace
        app.startButton.BackgroundColor = 'yellow';
        app.startButton.Text = 'Stopping';
%         SRControlState(app, 'on', {app.startButton, app.clearButton})
%         drawnow;
        % Stop immediately -- already stopping, so we've been asked to stop immediately
      else
        doStop(app);
        app.startButton.BackgroundColor = 'green';
        app.startButton.Text = 'Start';
        SRControlState(app, 'on', {app.startButton, app.clearButton})
        rescalePlot(app.isiPlot, app);                      % force the ISI histogram to replot
        drawnow;
      end
    end
    
  end

  methods (Access = private)
    

    function clearPlots(app)
      app.spikeIndices = [];
      app.inSpike = false;
      app.samplesRead = 0;
      app.lastSpikeIndex = 2 * app.maxContSamples;                          % flag start with invalid index
      clearAll(app.tracePlots, app);
      clearAll(app.isiPlot, app);
    end
    
    function closeSR(app, ~, ~)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle

      % fist check whether the task is running
      if ~strcmp(app.startButton.Text, 'Start')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                              % close the program window
        case 'Cancel'
          return
      end
    end
    
    function collectData(app, ~, ~)
      % reads and processes stream data from the LabJack
      % If the continuous display scaling has just changed, we will refresh that plot
      if app.contPlotRescale
        setLimits(app);
        clearAll(app.tracePlots, app);
        app.contPlotRescale = false;
      end
      % We need a new trace if we hit or exceeded the sample buffer limit on the previous call
      if (app.samplesRead >= app.contSamples)
        app.samplesRead = 0;
        app.lastSpikeIndex = app.lastSpikeIndex - app.contSamples;
        if app.stopAtTraceEnd                                         % if stopAtTraceEnd, stop data collection
          app.tracePlots.samplesPlotted = 0;
          doStartButton(app);
        end
      end
      if (app.tracePlots.samplesPlotted > app.samplesRead)
        if app.singleSpikeCheckbox.Value
          clearContPlot(app.tracePlots, app);
        else
          clearAll(app.tracePlots, app);
        end
      end
      
      % read and process any new data
      
      [dRaw, ~] = getStreamData(app.lbj);                         % get stream data
      
      % the LabJack marks lost data as -9999.  We clip it out and don't do anything more. Typically lost data appear
      % at the end of the trace (after the buffer has filled).  Oddly, there is generally one spurious large value
      % (+10.115) that appears exactly 8 samples before the first error values.  We clip that out as well.
      
      dRaw(dRaw > 10.0) = [];                                         % and clip out all error values
      dRaw(dRaw == -9999) = [];                                         % and clip out all error values
      %     firstErrorIndex = find(dRaw == -9999, 1, 'first');              % get the first error index
      %     if ~isempty(firstErrorIndex)                                    % for one or more errors,
      %         fprintf('found an error\n');
      %         for i = firstErrorIndex - 10:firstErrorIndex + 10
      %             fprintf('%.3f ', dRaw(i));
      %         end
      %         fprintf('\n');
      %         if (firstErrorIndex > 8)                                    % clip out the spurious erroneous value
      %             dRaw(firstErrorIndex - 8) = [];
      %         end
      %         dRaw(dRaw == -9999) = [];                                   % and clip out all error values
      %     end
      
      % now we can process any valid data
      
      numNew = min(length(dRaw), app.contSamples - app.samplesRead);
      if numNew > 0
        app.rawData(app.samplesRead + 1:app.samplesRead + numNew) = dRaw(1:numNew);
        processSignals(app.signals, app, app.samplesRead, numNew);
        app.samplesRead = app.samplesRead + numNew;
        doPlot(app.tracePlots, app);
        if numNew < length(dRaw)                                    % more data read from LabJack?
          if app.stopAtTraceEnd                                     % if stopAtTraceEnd, stop data collection
            app.tracePlots.samplesPlotted = 0;
            app.samplesRead = 0;
            doStartButton(app);
          else
            numNew = length(dRaw) - numNew;
            app.rawData(1:numNew) = dRaw(end - numNew + 1:end);
            processSignals(app.signals, app, 0, numNew);
            app.samplesRead = numNew;
          end
        end
      end
    end
    
    function collectError(~, ~, ~)
      fprintf('data error');
      dbstack
    end
    
    function doStart(app)
      dataCollectRateHz = 25;                                 % prevent overflow w/o blocking other activity
      app.dataTimer = timer('name', 'CollectData', 'ExecutionMode', 'fixedRate', 'busyMode', 'drop',...
        'Period', 1.0 / dataCollectRateHz, 'ErrorFcn', {@app.collectError},...
        'TimerFcn', {@app.collectData}, 'StartDelay', 0.050);   % startDelay allows rest of the gui to execute
      
      % create timer to make fake spikes for LabJack
      if app.testMode
        fakeSpikeRateHz = 4;
        app.fakeSpikeTimer = timer('Name', 'FakeSpikes', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0 / fakeSpikeRateHz, 'ErrorFcn', {@app.fakeSpikeError},...
          'TimerFcn', {@app.fakeSpike}, 'StartDelay', 0.050);
      end
      
      % set the gui button to "running" state
      % clear the plots
      if app.singleSpikeCheckbox.Value
        clearContPlot(app.tracePlots, app);
      else
        clearAll(app.tracePlots, app);
        app.lastSpikeIndex = 2 * app.maxContSamples;      % flag start of ISI sequence
      end
      startStream(app.lbj);
      
      % Start plots, data pickup, and data acquisition
      start(app.dataTimer);
      if app.testMode
        start(app.fakeSpikeTimer);
      end
    end
    
    %% doStop -- stop data collection
    function doStop(app)
      try stop(timerfind); catch, end
      try delete(timerfind); catch, end
      app.dataTimer = [];
      app.fakeSpikeTimer = [];
      stopStream(app.lbj);
      clearAll(app.signals, app);                              % stop audio processing
    end
    
    function fakeSpike(app, ~, ~)
      persistent count;
      
      if isempty(count)
        count = 1;
      else
        count = count + 1;
      end
%       numSpikes = 1 + (mod(count, 5) == 0);
      numSpikes = 1;
      for s = 1:numSpikes
        analogOut(app.lbj, 0, 4.5);
        java.lang.Thread.sleep(1.5);
        analogOut(app.lbj, 0, 1.5);
        java.lang.Thread.sleep(3.0);
        analogOut(app.lbj, 0, 2.5);
        java.lang.Thread.sleep(3.0);
      end
    end
    
    function fakeSpikeError(~, ~)
      fprintf('fake spike error\n');
    end
    
    function lbj = openLabJack(~)
      %  get hardware info and do not continue if daq device/drivers unavailable
      lbj = labJackU6;                        % create the daq object
      open(lbj);                              % open connection to the daq
      if isempty(lbj.handle)
        originalSize = get(0, 'DefaultUIControlFontSize');
        set(0, 'DefaultUIControlFontSize', 14);
        questdlg('Exit and check USB connections.', 'No LabJack Device Found', 'OK', 'OK');
        set(0, 'DefaultUIControlFontSize', originalSize);
      else
        fprintf(1,'StretchReceptor: LabJack Ready\n\n');
      end
      % create input channel list
      removeChannel(lbj, -1);                     % remove all input channels
      addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
      lbj.SampleRateHz = 5000;                    % sample rate (Hz)
      lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
      
      % configure LabJack for analog input streaming
      
      errorCode = streamConfigure(lbj);
      if errorCode > 0
        fprintf(1,'StretchReceptor: Unable to configure LabJack. Error %d.\n',errorCode);
        return
      end
    end
    
    %% setLimits -- used when plot scaling changes
    function setLimits(app)
      app.samplesRead = 0;
      app.spikeIndices = [];
      app.contSamples = app.contMSPerDiv / 1000.0 * app.lbj.SampleRateHz * app.contTimeDivs;
      vLimit = app.vPerDiv * app.vDivs / 2.0;
      threshV = app.thresholdSlider.Value;
      threshV = max(-vLimit * 0.9, min(vLimit * 0.9, threshV));
      app.thresholdSlider.Value = threshV;
      app.thresholdV = threshV;
      app.thresholdSlider.Limits = [-vLimit, vLimit];
    end
    
  end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openSR(app, varargin)
      set(app.figure1, 'visible', 'off');
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        if strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test')
          app.testMode = true;
        end
      end
      if app.testMode
        app.warnText.Text = 'Test Mode';
      end
      
      app.contMSPerDiv = str2double(app.contMSPerDivButton.Items{endsWith(app.contMSPerDivButton.Items, ...
        app.contMSPerDivButton.Value)});
      
      set(app.figure1, 'CloseRequestFcn', {@app.closeSR});            % close function will close LabJack
      app.lbj = openLabJack(app);                                     % LJ first, it has no contigencies
      maxMSPerDiv = str2double(app.contMSPerDivButton.Items{end});
      app.maxContSamples = app.contTimeDivs * maxMSPerDiv / 1000.0 * app.lbj.SampleRateHz;
      app.filteredTrace = zeros(app.maxContSamples, 1);               % filtered voltage trace
      app.rawData = zeros(app.maxContSamples, 1);                     % raw data
      app.rawTrace = zeros(app.maxContSamples, 1);                    % continuous voltage trace
      setLimits(app);
      filterStrings = app.filterMenu.Items;
      app.filters = cell(length(filterStrings), 1);
      for f = 1:length(filterStrings)
        cutOffHz = str2double(filterStrings{f});
        theFilter = design(fdesign.highpass('Fst,Fp,Ast,Ap', 0.5 * cutOffHz / app.lbj.SampleRateHz, ...
          2 * cutOffHz / app.lbj.SampleRateHz, 60, 1), 'butter');
        theFilter.persistentmemory = true;                            % piecemeal filtering of trace
        theFilter.states = 1;                                         % uses scalar expansion.
        app.filters{f} = theFilter;
        if strcmp(filterStrings{f}, app.filterMenu.Value)
          app.filter = theFilter;                                     % set the active filter
        end
      end
      app.isiPlot = SRISIPlot(app);                                   % create isi plot before signal processing
      app.signals = SRSignalProcess(app);                             % create signal processing after data
      app.tracePlots = SRPlots(app);
      if app.testMode
        analogOut(app.lbj, 0, 2.5);                                   % For debugging (AOuts to AIns)
      end
      movegui(app.figure1, 'northeast');
      set(app.figure1, 'visible', 'on');
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      if strcmp(app.startButton.Text, 'Start')                  % currently paused
        clearPlots(app);                                        % clear immediately
        atISILimit(app);                                        % update the warning text
      else
        doStop(app);                                            % stop data collection
        clearPlots(app);                                        % clear the plots and data
        doStart(app);                                           % re-start data collection
      end
    end

    % Value changed function: contMSPerDivButton
    function contMSPerDivButton_Callback(app, event)
      newValue = str2double(app.contMSPerDivButton.Value);
      if newValue ~= app.contMSPerDiv
        app.contMSPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')  % if we're not running
          setLimits(app);
          clearContPlot(app.tracePlots, app)
        else
          app.contPlotRescale = true;                % else let dataCollect do the update
        end
      end
    end

    % Value changed function: filterMenu
    function filterMenu_Callback(app, event)
      app.filter = app.filters{strcmp(app.filterMenu.Items, app.filterMenu.Value)};
    end

    % Value changed function: maxISIMenu
    function maxISIMenu_Callback(app, event)
      setISIMaxS(app.isiPlot, app);
    end

    % Button pushed function: retriggerButton
    function retriggerButton_Callback(app, event)
      clearTriggerPlot(app.tracePlots, app);
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      SRControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/SRData', ...
        'indeterminate','on');
      timeString = savePlot(app.vContAxes, '~/Desktop/SRData/FullTrace/', 'SR', 'png');
      savePlot(app.vTrigAxes, '~/Desktop/SRData/SpikeTrace/', 'SR', 'png', timeString);
      savePlot(app.isiAxes, '~/Desktop/SRData/ISI/', 'SR', 'png', timeString);       
      filePath = '~/Desktop/SRData/Matlab/';
      if ~isfolder(filePath)                      % folder exist?
        mkdir(filePath);
      end
      fileName = sprintf('SR%s.mat', timeString);
      isiMS = app.isiPlot.isiMS(1:app.isiPlot.isiNum);
      save([filePath fileName], 'isiMS');
      backupFile([filePath fileName], '~/Desktop', '~/Documents/Respository');
      saveStats(app.isiPlot, app, '~/Desktop/SRData/Excel/');
%       filePath = '~/Desktop/SRData/Excel/';
%       if ~isfolder(filePath)                      % folder exist?
%         mkdir(filePath);
%       end
%       fileName = sprintf('SR-%s.xlsx', timeString);
%       t = table(isiMS);
%       writetable(t, [filePath fileName]);
      backupFile([filePath fileName], '~/Desktop', '~/Documents/Respository');
      close(progress);
      SRControlState(app, 'on', {})
    end

    % Value changed function: singleSpikeCheckbox
    function singleSpikeCheckbox_Callback(app, event)
      if app.singleSpikeCheckbox.Value
        app.retriggerButton.Enable = true;
        clearTriggerPlot(app.tracePlots, app);
      else
        app.retriggerButton.Enable = false;
      end
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      doStartButton(app)
    end

    % Value changed function: thresholdSlider
    function thresholdSlider_Callback(app, event)
      app.thresholdV = app.thresholdSlider.Value;
      clearTriggerPlot(app.tracePlots, app);
    end

    % Value changed function: vPerDivButton
    function vPerDivButton_Callback(app, event)
      newValue = str2double(app.vPerDivButton.Value);
      if newValue ~= app.vPerDiv
        app.vPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')  % if we're not running
          setLimits(app);
          clearAll(app.tracePlots, app);
        else
          app.contPlotRescale = true;                % else let dataCollect do the update
        end
      end
    end

    % Value changed function: volumeSlider
    function volumeSlider_Callback(app, event)
      setVolume(app.signals, app);
    end

    % Value changed function: stopAfterText
    function stopAfterTextChanged(app, event)
      atISILimit(app);
    end

    % Value changed function: spikesPerISIText
    function spikesPerISI_Callback(app, event)
      app.spikesPerISI = str2double(app.spikesPerISIText.Value);
      clearButton_Callback(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Tag = 'figure1';
      app.vContAxes = uiaxes(app.figure1);
      app.vContAxes.Tag = 'axes1';
      app.vTrigAxes = uiaxes(app.figure1);
      app.vTrigAxes.Tag = 'axes2';
      app.isiAxes = uiaxes(app.figure1);
      app.isiAxes.Tag = 'axes3';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Reset Counters';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Data';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Stretch Receptor Laboratory';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.contMSPerDivButton = uidropdown(app.figure1);
      app.contMSPerDivButton.Items = {'10', '25', '50', '100', '250', '500', '1000'};
      app.contMSPerDivButton.ValueChangedFcn = createCallbackFcn(app, @contMSPerDivButton_Callback, true);
      app.contMSPerDivButton.Tag = 'contMSPerDivButton';
      app.contMSPerDivButton.Value = '100';
      app.text9 = uilabel(app.figure1);
      app.text9.Tag = 'text9';
      app.text9.Text = ' Time per Division (ms):';
      app.thresholdSlider = uislider(app.figure1);
      app.thresholdSlider.Limits = [-1 1];
      app.thresholdSlider.MajorTicks = [];
      app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
      app.thresholdSlider.MinorTicks = [];
      app.thresholdSlider.Tag = 'thresholdSlider';
      app.thresholdSlider.Value = 1;
      app.vPerDivButton = uidropdown(app.figure1);
      app.vPerDivButton.Items = {'0.1', '0.2', '0.5', '1.0', '2.0', '5.0'};
      app.vPerDivButton.ValueChangedFcn = createCallbackFcn(app, @vPerDivButton_Callback, true);
      app.vPerDivButton.Tag = 'vPerDivButton';
      app.vPerDivButton.Value = '1.0';
      app.text10 = uilabel(app.figure1);
      app.text10.Tag = 'text10';
      app.text10.Text = ' Volts per Division:';
      app.singleSpikeCheckbox = uicheckbox(app.figure1);
      app.singleSpikeCheckbox.ValueChangedFcn = createCallbackFcn(app, @singleSpikeCheckbox_Callback, true);
      app.singleSpikeCheckbox.Tag = 'singleSpikeCheckbox';
      app.singleSpikeCheckbox.Text = 'Single Spike';
      app.retriggerButton = uibutton(app.figure1, 'push');
      app.retriggerButton.ButtonPushedFcn = createCallbackFcn(app, @retriggerButton_Callback, true);
      app.retriggerButton.Tag = 'retriggerButton';
      app.retriggerButton.Enable = 'off';
      app.retriggerButton.Text = 'Re-trigger';
      app.maxISIMenu = uidropdown(app.figure1);
      app.maxISIMenu.Items = {'0.10', '0.25', '0.50', '1.0', '2.5', '5.0', '10'};
      app.maxISIMenu.ValueChangedFcn = createCallbackFcn(app, @maxISIMenu_Callback, true);
      app.maxISIMenu.Tag = 'maxISIMenu';
      app.maxISIMenu.Value = '0.50';
      app.text11 = uilabel(app.figure1);
      app.text11.Tag = 'text11';
      app.text11.Text = 'X-Axis Maximum ISI (s):';
      app.filterMenu = uidropdown(app.figure1);
      app.filterMenu.Items = {'1', '100', '250'};
      app.filterMenu.ValueChangedFcn = createCallbackFcn(app, @filterMenu_Callback, true);
      app.filterMenu.Tag = 'filterMenu';
      app.filterMenu.Value = '1';
      app.text12 = uilabel(app.figure1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'High-pass Filter (Hz):';
      app.volumeSlider = uislider(app.figure1);
      app.volumeSlider.Limits = [2 6];
      app.volumeSlider.MajorTicks = [];
      app.volumeSlider.ValueChangedFcn = createCallbackFcn(app, @volumeSlider_Callback, true);
      app.volumeSlider.MinorTicks = [];
      app.volumeSlider.Tag = 'volumeSlider';
      app.volumeSlider.Value = 3;
      app.text13 = uilabel(app.figure1);
      app.text13.Tag = 'text13';
      app.text13.Text = 'Volume';
      app.stopAfterText = uieditfield(app.figure1, 'text');
      app.stopAfterText.ValueChangedFcn = createCallbackFcn(app, @stopAfterTextChanged, true);
      app.stopAfterText.Tag = 'stopAfterText';
      app.stopAfterText.Value = '100';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Stop When ISI Count =';
      app.limitText = uilabel(app.figure1);
      app.limitText.Tag = 'limitText';
      app.limitText.Text = '';
      app.spikesPerISIText = uieditfield(app.figure1, 'text');
      app.spikesPerISIText.ValueChangedFcn = createCallbackFcn(app, @spikesPerISI_Callback, true);
      app.spikesPerISIText.Tag = 'spikesPerISI';
      app.spikesPerISIText.Value = '1';
      app.text2_2 = uilabel(app.figure1);
      app.text2_2.Tag = 'text2';
      app.text2_2.Text = 'Spikes per ISI:';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = StretchReceptor(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openSR(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end